classdef coAppearance_inspector_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figureWindow                matlab.ui.Figure
        redText                     matlab.ui.control.Label
        preyButtonPanel             matlab.ui.container.ButtonGroup
        preyButton3                 matlab.ui.control.RadioButton
        preyButton2                 matlab.ui.control.RadioButton
        preyButton1                 matlab.ui.control.RadioButton
        tracePanel                  matlab.ui.container.Panel
        esc_text                    matlab.ui.control.Label
        traceAxes                   matlab.ui.control.UIAxes
        buttonPanel                 matlab.ui.container.ButtonGroup
        ignoreNonAppearingCheckBox  matlab.ui.control.CheckBox
        ignoreLateCheckBox          matlab.ui.control.CheckBox
        ignoreBlinkerCheckBox       matlab.ui.control.CheckBox
        colocCheckbox               matlab.ui.control.CheckBox
        greenCheckbox               matlab.ui.control.CheckBox
        DifferenceImagesButton      matlab.ui.control.RadioButton
        AveragedImagesButton        matlab.ui.control.RadioButton
        arrayPosSlider              matlab.ui.control.Slider
        busyTextPanel               matlab.ui.container.Panel
        busyText                    matlab.ui.control.Label
        artifactPanel               matlab.ui.container.Panel
        uibuttongroup1              matlab.ui.container.ButtonGroup
        roiAllImagesButton          matlab.ui.control.RadioButton
        roiThisImageButton          matlab.ui.control.RadioButton
        text12                      matlab.ui.control.Label
        trimDataButton              matlab.ui.control.Button
        reRegisterButton            matlab.ui.control.Button
        defineROIbutton             matlab.ui.control.Button
        excludeROIbutton            matlab.ui.control.Button
        drawROIbutton               matlab.ui.control.Button
        loadButton                  matlab.ui.control.Button
        greenText                   matlab.ui.control.Label
        colocText                   matlab.ui.control.Label
        positionText                matlab.ui.control.Label
        mergedImagePanel            matlab.ui.control.UIAxes
        redImagePanel               matlab.ui.control.UIAxes
        greenImagePanel             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        circle = uint16([    0,      0,      0,  65500,  65500,  65500,  65500,  65500,      0,      0,      0;
                             0,      0,  65500,      0,      0,      0,      0,      0,  65500,      0,      0;
                             0,  65500,      0,      0,      0,      0,      0,      0,      0,  65500,      0;
                         65500,      0,      0,      0,      0,      0,      0,      0,      0,      0,  65500;
                         65500,      0,      0,      0,      0,      0,      0,      0,      0,      0,  65500;
                         65500,      0,      0,      0,      0,      0,      0,      0,      0,      0,  65500;
                         65500,      0,      0,      0,      0,      0,      0,      0,      0,      0,  65500;
                         65500,      0,      0,      0,      0,      0,      0,      0,      0,      0,  65500;
                             0,  65500,      0,      0,      0,      0,      0,      0,      0,  65500,      0;
                             0,      0,  65500,      0,      0,      0,      0,      0,  65500,      0,      0;
                             0,      0,      0,  65500,  65500,  65500,  65500,  65500,      0,      0,      0]); %Circle drawn on images
         
         arrayPos % Where we are in the image sequence
         whichPreyCh = 1; % Which prey channel is being displayed
         dynData % Main data structure
         imgName % File name prefix of images
         matPath % Location of data
         matFile % Holds the data
         
         % Images
         baitAvg
         baitDiff
         preyAvg
         preyDiff
         avgImg
         diffImg
         
         % Properties of data
         params
         nWindows
         imgSize
         activeBaitSpots
         activePreySpots
         blinkerIndex
         appearIndex
         lateIndex
         
         % Display properties
         screenSize
         scale
        
         ROI % ROI to be excluded or kept in data
    end
    
    methods (Access = private)
        
        function displayImages(app)
            set(app.tracePanel,'Visible','off');
            set(app.busyTextPanel,'Visible','on');
            drawnow;
            b=app.arrayPos;
            showAvg = get(app.AveragedImagesButton,'Value');
            if b==1 && ~showAvg %Prevents errors when loading a new dataset in difference view
                b=2;
            end
            
            % Prepare bait channel image
            if strcmp(app.params.DataType,'Composite Data')
                if(showAvg) % True if we're viewing the averaged image
                    greenCh = app.avgImg(:,:,app.params.baitChNum,b);
                else % Otherwise, we're viewing the difference image
                    greenCh = app.diffImg(:,:,app.params.baitChNum,b-1); % "b-1" because the difference image is offset by one from the bait image
                end
                [ymax, xmax] = size(greenCh);
                greenCh = imadjust(greenCh,stretchlim(greenCh,0.0005),[]);
            else
                if(showAvg) % True if we're viewing the averaged image
                   greenCh = app.baitAvg(:,:,b);
                else % Otherwise, we're viewing the difference image
                   greenCh = app.baitDiff(:,:,b-1); % "b-1" because the difference image is offset by one from the bait image
                end
                [ymax, xmax] = size(greenCh);
                greenCh = imadjust(greenCh,stretchlim(greenCh,0.0005),[]);
                if isfield(app.params, 'RegistrationData') && strcmp(app.params.BaitPos, 'Right') %Apply registration correct if applicable
                    greenCh = imwarp(greenCh,app.params.RegistrationData.SpatialRefObj, app.params.RegistrationData.Transformation,'OutputView', imref2d(size(greenCh)));
                end
            end
                
            % Prepare prey channel image
            if strcmp(app.params.DataType,'Composite Data')
                chNum = app.params.preyChNums(app.whichPreyCh);
                if(showAvg) % True if we're viewing the averaged image
                    redCh = app.avgImg(:,:,chNum,b);
                else % Otherwise, we're viewing the difference image
                    redCh = app.diffImg(:,:,chNum,b-1); % "b-1" because the difference image is offset by one from the bait image
                end
                redCh = imadjust(redCh,stretchlim(redCh,0.0005),[]);
                if isfield(app.params, 'RegistrationData') && ~isempty(app.params.RegistrationData(chNum).Transformation) %Apply registration correct if applicable
                    redCh = imwarp(redCh,app.params.RegistrationData(chNum).SpatialRefObj, app.params.RegistrationData(chNum).Transformation,'OutputView', imref2d(size(redCh)));
                end
            else
                if(showAvg) % True if we're viewing the averaged image
                    redCh = app.preyAvg(:,:,b);
                else % Otherwise, we're viewing the difference image
                    redCh = app.preyDiff(:,:,b-1);
                end
                redCh = imadjust(redCh,stretchlim(redCh,0.0005),[]);
                if isfield(app.params, 'RegistrationData') && strcmp(app.params.BaitPos, 'Left') %Apply registration correct if applicable
                    redCh = imwarp(redCh,app.params.RegistrationData.SpatialRefObj, app.params.RegistrationData.Transformation,'OutputView', imref2d(size(redCh)));
                end
            end
            
            %Create images for display
            greenImage = uint16(zeros(ymax,xmax,3));
            greenImage(:,:,1) = greenCh;
            greenImage(:,:,2) = greenCh;
            greenImage(:,:,3) = greenCh;
            
            redImage = uint16(zeros(ymax,xmax,3));
            redImage(:,:,1) = redCh;
            redImage(:,:,2) = redCh;
            redImage(:,:,3) = redCh;
            
            mergedImage = uint16(zeros(ymax,xmax,3));
            mergedImage(:,:,1) = redCh;
            mergedImage(:,:,2) = greenCh;
            mergedImage(:,:,3) = redCh; %This makes magenta
            
            %Figure out which spots appeared in the currently active window 
            baitCh = app.params.BaitChannel;
            if strcmp(app.params.DataType,'Composite Data')
                preyCh = ['PreyCh' num2str(chNum)];
            else
                preyCh = app.params.PreyChannel;
            end
            
            if b ==1
                index = cell2mat({app.dynData.([baitCh 'SpotData']).appearedInWindow}) == b;
            else
                index = cell2mat({app.dynData.([baitCh 'SpotData']).appearedInWindow}) == b-1; %b-1 is used here because "appearedInWindow" actually refers to the difference image, not the averaged image
            end
            %Apply filters, if seclected
            if app.ignoreBlinkerCheckBox.Value
                index = index & ~app.blinkerIndex;
            end
            if app.ignoreNonAppearingCheckBox.Value
                index = index & app.appearIndex;
            end
            if app.ignoreLateCheckBox.Value
                index = index & ~app.lateIndex;
            end
            %Get the spots to display
            app.activeBaitSpots = app.dynData.([baitCh 'SpotData'])(index);
            app.activePreySpots = app.dynData.([preyCh 'SpotData'])(index);
           %For most of the dataset we're now done, but when working with averaged images the first two frames get special treatment
            if showAvg
                if b==1
                    index3 = cellfun(@isnumeric,{app.activeBaitSpots.appearTimeFrames});
                    app.activeBaitSpots = app.activeBaitSpots(index3);
                    index2 = cell2mat({app.activeBaitSpots.appearTimeFrames}) < 50;
                    app.activeBaitSpots = app.activeBaitSpots(index2);
                elseif b==2
                    index3 = cellfun(@isnumeric,{app.activeBaitSpots.appearTimeFrames});
                    app.activeBaitSpots = app.activeBaitSpots(index3);
                    index2 = cell2mat({app.activeBaitSpots.appearTimeFrames}) >= 50;
                    app.activeBaitSpots = app.activeBaitSpots(index2);
                end
            end
            
            %Draw circles around spots
            greenOn = get(app.greenCheckbox,'Value');
            colocOn = get(app.colocCheckbox,'Value');
            
            if ~isempty(app.activeBaitSpots) && (greenOn || colocOn)
                for a = 1:length(app.activeBaitSpots)
                    if ~strcmp(app.params.DataType,'Composite Data') && isfield(app.params, 'RegistrationData') && strcmp(app.params.BaitPos, 'Right') %Apply registration correction if applicable
                        [xcoord,ycoord] = transformPointsForward(app.params.RegistrationData.Transformation, app.activeBaitSpots(a).spotLocation(1), app.activeBaitSpots(a).spotLocation(2));
                        xcoord = round(xcoord);
                        ycoord = round(ycoord);
                        if (xcoord <= 5 || xcoord+5 > xmax || ycoord <= 5 || ycoord+5 > ymax)
                            continue
                        end
                    else
                        xcoord = app.activeBaitSpots(a).spotLocation(1);
                        ycoord = app.activeBaitSpots(a).spotLocation(2);
                    end
                                
                    %Green Circles for Spots in Green Channel
                    if greenOn 
                        greenImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2) = max(app.circle, greenImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2));
                        redImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2) = max(app.circle, redImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2));
                        mergedImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2) = max(app.circle, mergedImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2));
                    end
                    %White Circles for Colocalized Spots
                    if colocOn && ~isnan( app.activeBaitSpots(a).(['appears_w_' preyCh]) ) && ~isempty( app.activeBaitSpots(a).(['appears_w_' preyCh]) ) && app.activeBaitSpots(a).(['appears_w_' preyCh])
                    % Red
                    greenImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1) = max(app.circle, greenImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1));
                    redImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1) = max(app.circle, redImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1));
                    mergedImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1) = max(app.circle, mergedImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1));
                    % Blue 
                    greenImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,3) = max(app.circle, greenImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,3));
                    redImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,3) = max(app.circle, redImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,3));
                    mergedImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,3) = max(app.circle, mergedImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,3));                      
                    end
                end
            end
            
            %Show images
            I = imshow(greenImage,'Parent',app.greenImagePanel,'XData', [1 app.greenImagePanel.Position(3)],'YData', [1 app.greenImagePanel.Position(4)]);
            app.greenImagePanel.XAxis.TickLabels = {};
            app.greenImagePanel.YAxis.TickLabels = {};
            set(app.greenImagePanel, 'XLim', [0 I.XData(2)], 'YLim', [0 I.YData(2)]);
            
            I = imshow(redImage,'Parent',app.redImagePanel,'XData', [1 app.redImagePanel.Position(3)],'YData', [1 app.redImagePanel.Position(4)]);
            app.redImagePanel.XAxis.TickLabels = {};
            app.redImagePanel.YAxis.TickLabels = {};
            set(app.redImagePanel, 'XLim', [0 I.XData(2)], 'YLim', [0 I.YData(2)]);
            
            I = imshow(mergedImage,'Parent',app.mergedImagePanel,'XData', [1 app.mergedImagePanel.Position(3)],'YData', [1 app.mergedImagePanel.Position(4)]);
            app.mergedImagePanel.XAxis.TickLabels = {};
            app.mergedImagePanel.YAxis.TickLabels = {};
            set(app.mergedImagePanel, 'XLim', [0 I.XData(2)], 'YLim', [0 I.YData(2)]);
            
            set(app.greenText,'Text',[num2str(length(app.activeBaitSpots)) ' ' app.params.BaitChannel ' bait spots']);
            set(app.redText,'Text','Prey Channel');
            index4 = ~cellfun(@isnan, {app.activeBaitSpots.(['appears_w_' preyCh])} );
            n_coAppearing = sum(cell2mat( {app.activeBaitSpots(index4).(['appears_w_' preyCh])} ));
            set(app.colocText,'Text',[num2str(n_coAppearing) ' ' preyCh ' prey spots co-appeared with bait']);
            
            %Update visibility of components
            set(app.positionText,'Text',['Current frame: ' num2str(b)] );
            set(app.arrayPosSlider,'Value',b);
            set(app.busyTextPanel,'Visible','off');
            set(app.excludeROIbutton,'Enable','off');
            set(app.defineROIbutton,'Enable','off');
            if b == 1
                set(app.AveragedImagesButton,'Enable','off');
                set(app.DifferenceImagesButton,'Enable','off');
            else
                set(app.AveragedImagesButton,'Enable','on');
                set(app.DifferenceImagesButton,'Enable','on');
            end
        end
        
        function app = initializeData(app)
            b=1;
            app.arrayPos = b;
            app.figureWindow.Visible = 'off';
            %Get the data from the automated processing
            if ~isempty(app.matPath)
                startPath = app.matPath;
            else
                startPath = pwd;
            end
            dummyFig = figure('Visible','off');
            [app.matFile, app.matPath] = uigetfile('*.mat','Choose a .mat file with dynamic data from the automated analysis',startPath);
            delete(dummyFig);
            app.figureWindow.Visible = 'on';
            load([app.matPath filesep app.matFile]);
            app.dynData = dynData; clear dynData;
            
            %Check for necessary parameters, get from dialog box if missing
            if ~strcmp(params.DataType,'Composite Data') && ~isfield(params, 'BaitPos')
                [Answer,Cancelled] = dynamicChannelInfoDlg_short;
                if Cancelled 
                    return
                else
                    v2struct(Answer);
                    params.LeftChannel = LeftChannel;
                    params.RightChannel = RightChannel;
                    params.BaitPos = BaitPos;
                    save([app.matPath filesep app.matFile],'params','-append');
                end
            end
            if ~isfield(params,'BaitChannel')        
                params.BaitChannel = params.([params.BaitPos 'Channel']);
                if strcmp(params.BaitPos,'Right')
                    params.PreyChannel = params.LeftChannel;
                else
                    params.PreyChannel = params.RightChannel;
                end
                save([app.matPath filesep app.matFile],'params','-append');
            end
            app.params = params; clear params;
            
            %This maintains compatibility with older data
            if ~isfield(app.dynData.([app.params.BaitChannel 'SpotData']), 'appearTimeFrames')
                [app.dynData.([app.params.BaitChannel 'SpotData']).appearTimeFrames] = deal(app.dynData.([app.params.BaitChannel 'SpotData']).appearTime);
            end


            %% Load images
            warning('off'); %Prevent unnecessary warnings from libtiff
            app.imgName = app.matFile(1:end-4);
            % Check data type
            if strcmp(app.params.DataType,'Composite Data')
                % Load composite images
                avgFile = [app.matPath filesep app.imgName '_avgImg.tif'];
                diffFile = [app.matPath filesep app.imgName '_diffImg.tif'];
                app.busyText.Text = 'Loading Images...';
                drawnow;
                app.avgImg = TIFFStack(avgFile,[],app.params.nChannels);
                app.diffImg = TIFFStack(diffFile,[],app.params.nChannels);
                [ymax,xmax,~,app.nWindows] = size(app.avgImg);
                app.imgSize = [xmax ymax];
            else
                %% This entire section handles old non-composite data
                % Get / generate bait channel images
                if contains(app.imgName, 'greedy')
                   excess = "_greedy";
                   snipsnop = regexp(app.imgName,excess,'split');
                   app.imgName = snipsnop{1};
                end

                baitAvgFile = [app.matPath filesep app.imgName '_baitAvg.tif'];
                baitDiffFile = [app.matPath filesep app.imgName '_baitDiff.tif'];
                app.busyText.Text = 'Loading Bait Images...';
                drawnow;
                if exist(baitAvgFile,'file') && exist (baitDiffFile, 'file')
                    %If both files exist, load them
                    app.baitAvg = TIFFStack(baitAvgFile);
                    app.baitDiff = TIFFStack(baitDiffFile);
                    [ymax,xmax,app.nWindows] = size(app.baitAvg);
                else
                    % If either file is missing, we need to create them
                    stackObj = loadRawImages(app);
                    %Calculate windowed average image and difference
                    app.busyText.Text = 'Averaging Images...';
                    app.baitAvg = windowMean(stackObj,app.dynData.avgWindow,app.params.BaitPos); 
                    app.baitDiff = diff(app.baitAvg,1,3); % "1" for first derivative, "3" for third dimension
                    [ymax,xmax,app.nWindows] = size(app.baitAvg);
                    %Convert to uint16
                    app.baitAvg = uint16(app.baitAvg);
                    app.baitDiff = uint16(app.baitDiff);
                    %Save
                    for a = 1:app.nWindows
                        imwrite(app.baitAvg(:,:,a),[app.matPath filesep app.imgName '_baitAvg.tif'],'tif','WriteMode','append','Compression','none');
                    end
                    for d = 1:app.nWindows-1
                        imwrite(app.baitDiff(:,:,d),[app.matPath filesep app.imgName '_baitDiff.tif'],'tif','WriteMode','append','Compression','none');
                    end
                end
            
                app.imgSize = [xmax ymax];
                
                % Get / generate prey channel images
                preyAvgFile = [app.matPath filesep app.imgName '_preyAvg.tif'];
                preyDiffFile = [app.matPath filesep app.imgName '_preyDiff.tif'];
                app.busyText.Text = 'Loading Prey Images...';
                drawnow;
                if exist(preyAvgFile,'file') && exist(preyDiffFile,'file')
                    %If both files exist, load them
                    app.preyAvg = TIFFStack(preyAvgFile);
                    app.preyDiff = TIFFStack(preyDiffFile);
                else
                    % If either file is missing, we need to create them
                    if ~exist('stackObj','var')
                        stackObj = loadRawImages(app);
                    end
                    %Calculate windowed average image and difference
                    app.busyText.Text = 'Averaging Images...';
                    if strcmp(app.params.BaitPos, 'Right')
                        preyPos = 'Left';
                    else
                        preyPos = 'Right';
                    end
                    app.preyAvg = windowMean(stackObj,app.dynData.avgWindow,preyPos); 
                    app.preyDiff = diff(app.preyAvg,1,3); % "1" for first derivative, "3" for third dimension
                    %Convert to uint16
                    app.preyAvg = uint16(app.preyAvg);
                    app.preyDiff = uint16(app.preyDiff);
                    %Save
                    for a = 1:app.nWindows
                        imwrite(app.preyAvg(:,:,a),[app.matPath filesep app.imgName '_preyAvg.tif'],'tif','WriteMode','append','Compression','none');
                    end
                    for d = 1:app.nWindows-1
                        imwrite(app.preyDiff(:,:,d),[app.matPath filesep app.imgName '_preyDiff.tif'],'tif','WriteMode','append','Compression','none');
                    end
                end
            end %Done loading images

            getFilterIndices(app);
            
            %Set up figure window
            app.screenSize = get(0,'ScreenSize');
            figureWidth = app.screenSize(3);
            imageWidth = (figureWidth-120) / 3;
            xscale = imageWidth / xmax;
            figureHeight = app.screenSize(4);
            imageHeight = figureHeight - 300;
            yscale = imageHeight / ymax;
            app.scale = min([xscale yscale]);
            imageWidth = floor(app.scale * xmax);
            figureWidth = imageWidth*3 + 120;
            imageHeight = floor(app.scale * ymax);
            figureHeight = imageHeight + 300;
            set(app.figureWindow,'Name',app.matFile(1:end-4), 'Position',[0 app.screenSize(4)-figureHeight-100 figureWidth figureHeight]);
            set(app.greenImagePanel,'Position',[30 30 imageWidth imageHeight],'XLim',[0 imageWidth],'YLim',[0 imageHeight]);
            set(app.redImagePanel,'Position',[imageWidth+60 30 imageWidth imageHeight],'XLim',[0 imageWidth],'YLim',[0 imageHeight]);
            set(app.mergedImagePanel,'Position',[imageWidth*2+90 30 imageWidth imageHeight],'XLim',[0 imageWidth],'YLim',[0 imageHeight]);
            
            set(app.greenText,'Position',[30+(imageWidth-200)/2 imageHeight+30 200 20]);
            set(app.redText,'Position',[imageWidth+60+(imageWidth-200)/2 imageHeight+30 200 20])
            if app.params.nChannels == 2
                    set(app.preyButton1,'Text',['Channel' num2str(app.params.preyChNums)], 'Position',[5 15 100 20] );
                    set(app.preyButton2,'Visible','off');
                    set(app.preyButton3,'Visible','off'); 
                    set(app.preyButtonPanel,'Position',[imageWidth+60+(imageWidth-125)/2 imageHeight+50 125 70]);
            elseif app.params.nChannels == 3
                   set(app.preyButton1,'Text',['Channel' num2str(app.params.preyChNums(1))], 'Position',[5 40 100 20] );
                   set(app.preyButton2,'Visible','on', 'Text',['Channel' num2str(app.params.preyChNums(2))], 'Position',[5 15 100 20] );
                   set(app.preyButton3,'Visible','off');
                   set(app.preyButtonPanel,'Position',[imageWidth+60+(imageWidth-125)/2 imageHeight+50 125 90]);
            else
                   set(app.preyButton1,'Text',['Channel' num2str(app.params.preyChNums(1))], 'Position',[5 65 100 20] );
                   set(app.preyButton2,'Visible','on', 'Text',['Channel' num2str(app.params.preyChNums(2))], 'Position',[5 40 100 20] );
                   set(app.preyButton3,'Visible','on', 'Text',['Channel' num2str(app.params.preyChNums(3))], 'Position',[5 15 100 20] );
                   set(app.preyButtonPanel,'Position',[imageWidth+60+(imageWidth-125)/2 imageHeight+50 125 110]);
            end
            set(app.colocText,'Position',[2*imageWidth+90+(imageWidth-400)/2 imageHeight+30 400 20]);
            
            set(app.positionText,'Position',[(figureWidth-500)/2 imageHeight+220 500 20]);
            set(app.arrayPosSlider,'Position',[imageWidth+60 imageHeight+200 imageWidth 3]);
            set(app.arrayPosSlider, 'Limits',[1 app.nWindows]);
            
            set(app.buttonPanel,'Position',[30 imageHeight+100 500 100]);
            set(app.artifactPanel, 'Position',[2*imageWidth+40+(imageWidth-520)/2 imageHeight+150 520 100]);
            set(app.loadButton, 'Position',[30 imageHeight+50 150 30]);
            set(app.busyTextPanel,'Position',[(figureWidth-250)/2 imageHeight/2 250 80], 'Visible','off');
            set(app.tracePanel,'Visible','off');
            
            set(app.artifactPanel,'Position',[figureWidth-330 imageHeight+140 300 60] );

            app.ROI = [];
        end
        
        function getFilterIndices(app)
            %Get indices for filtering spots
            app.busyText.Text = 'Working...';
            drawnow;
            baitCh = app.params.BaitChannel;
            nspots = length(app.dynData.([baitCh 'SpotData']));
            app.blinkerIndex = false(1,nspots);
            app.appearIndex = false(1,nspots);
            app.lateIndex = false(1,nspots);
            for e = 1:nspots
                if isfield(app.dynData.([baitCh 'SpotData']),'nFramesSinceLastApp')
                    x = app.dynData.([baitCh 'SpotData'])(e).nFramesSinceLastApp;
                    app.blinkerIndex(e) = isnumeric(x) && length(x)==1 && ~isnan(x) && x<2500;
                end
                y = app.dynData.([baitCh 'SpotData'])(e).appearTimeFrames;
                app.appearIndex(e) = isnumeric(y);
                app.lateIndex(e) = isnumeric(y) && y > 50 * (app.dynData.([baitCh 'SpotData'])(e).appearedInWindow + 1);
            end
        end

        function stackObj = loadRawImages(app)
            % Locate images
            d = uipickfiles_subs.filtered_dir([app.matPath filesep '*.tif'],'',false,@(x,c)uipickfiles_subs.file_sort(x,[1 0 0],c)); % See comments in uipickfiles_subs for syntax here
            imgFile = arrayfun(@(x) [x.folder filesep x.name], d, 'UniformOutput', false);
            %Load images
            if length(imgFile) > 1 %if the directory contains multiple files
                nFiles = length(imgFile);
                stackOfStacks = cell(nFiles,1);
                for a = 1:nFiles
                    if exist(imgFile{a}) == 7 || contains(imgFile{a},'Avg.tif') %Skip any directories or existing average images
                        continue
                    else
                        stackOfStacks{a} = TIFFStack(imgFile{a});
                    end
                end
                index = ~cellfun(@isempty,stackOfStacks);
                stackOfStacks = stackOfStacks(index);
                if length(stackOfStacks) == 1
                    stackObj = stackOfStacks{1};
                else
                    stackObj = TensorStack(3, stackOfStacks{:});
                end
            else
                % If there's just a single TIFF file, it's simpler
                stackObj = TIFFStack(imgFile{1});
            end
        end
        
        %ROI functions
        function movedROI(app, ~, evt)
            app.ROI = evt.CurrentPosition;
        end
        
        function deletingROI(app)
            set(app.defineROIbutton,'Enable','off');
            set(app.excludeROIbutton,'Enable','off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function colocalization_inspector_GUI_OpeningFcn(app, varargin)
                                   
            %Load data from the spot counter and set up figure window
            app = initializeData(app);
            
            %Display Images
            displayImages(app);

        end

        % Value changed function: colocCheckbox
        function colocCheckbox_Callback(app, event)
            displayImages(app);
        end

        % Button pushed function: defineROIbutton
        function defineROIbutton_Callback(app, event)
            set(app.busyTextPanel,'Visible','on');
            drawnow;
            if get(app.roiAllImagesButton,'Value')
                % If "all images" is selected, do all
                wb = waitbar(0,'');
                for c=1:app.nWindows
                    waitbar((c-1)/app.nWindows, wb, ['Processing image ' num2str(c) ' of ' num2str(app.nWindows)]);
                    [app.dynData, app.statsByColor, ~] = defineROI(app.dynData, app.channels, app.nChannels, app.nWindows, app.params, app.statsByColor, app.matPath, app.matFile, c, app.ROI, 1);
                end
                set(app.roiAllImagesButton,'Value',0);
                set(app.roiThisImageButton,'Value',1);
                close(wb);
            else
                % Otherwise, just do the current image
                [app.dynData, app.statsByColor, ~] = defineROI(app.dynData, app.channels, app.nChannels, app.nWindows, app.params, app.statsByColor, app.matPath, app.matFile, app.arrayPos, app.ROI, 1);
            end
            
            displayImages(app);
        end

        % Button pushed function: drawROIbutton
        function drawROIbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            rect = drawrectangle(app.mergedImagePanel,'Color','cyan','FaceAlpha',0);
            app.ROI = rect.Position;
            set(app.defineROIbutton,'Enable','on');
            set(app.excludeROIbutton,'Enable','on');
            addlistener(rect, 'DeletingROI', @(src,evt) deletingROI(app));
            addlistener(rect, 'ROIMoved', @(src,evt) movedROI(app, src,evt));
        end

        % Button pushed function: excludeROIbutton
        function excludeROIbutton_Callback(app, event)
            set(app.busyTextPanel,'Visible','on');
            drawnow;
            if get(app.roiAllImagesButton,'Value')
                % If "all images" is selected, do all
                wb = waitbar(0,'');
                for c=1:app.nWindows
                    waitbar((c-1)/app.nWindows, wb, ['Processing image ' num2str(c) ' of ' num2str(app.nWindows)]);
                    [app.dynData, app.statsByColor, ~] = defineROI(app.dynData, app.channels, app.nChannels, app.nWindows, app.params, app.statsByColor, app.matPath, app.matFile, c, app.ROI, 0);
                end
                set(app.roiAllImagesButton,'Value',0);
                set(app.roiThisImageButton,'Value',1);
                close(wb);
            else
                % Otherwise, just do the current image
                [app.dynData, app.statsByColor, ~] = defineROI(app.dynData, app.channels, app.nChannels, app.nWindows, app.params, app.statsByColor, app.matPath, app.matFile, app.arrayPos, app.ROI, 0);
            end
            displayImages(app);
        end

        % Window key press function: figureWindow
        function figureWindow_WindowKeyPressFcn(app, event)
            
            %Decide what to do
            b = app.arrayPos;
            switch event.Key 
                case {'n','rightarrow'}
                    b = b+1;
                case {'p','leftarrow'}
                    b = b-1;
                case 'r'
                    b=round(rand(1)*app.nWindows);
                case {'backspace', 'delete'}
                    set(app.defineROIbutton,'Enable','off');
                    set(app.excludeROIbutton,'Enable','off');
                case {'escape'}
                    set(app.tracePanel,'Visible','off');
                    return %Return here because we don't need to redraw the images
                otherwise   
                    return
            end
            %If we're off the edge of the time series, get back into bounds
            if b<1
                b=app.nWindows;
            end
            if b>app.nWindows
                b=1;
            end
            if b==1 && get(app.DifferenceImagesButton,'Value')
                b=2;
            end
            
            app.arrayPos = b;
                       
            displayImages(app);
        end

        % Value changed function: greenCheckbox
        function greenCheckbox_Callback(app, event)
            displayImages(app);
        end

        % Button pushed function: loadButton
        function loadButton_Callback(app, event)
            set(app.busyTextPanel,'Visible','on');
            drawnow;
            app = initializeData(app);

            displayImages(app);
        end

        % Button pushed function: reRegisterButton
        function reRegisterButton_Callback(app, event)
            set(app.busyTextPanel,'Visible','on');
            drawnow;
            % [app.dynData, app.statsByColor, ~] = reRegister(app.dynData, app.channels, app.nChannels, app.params, app.statsByColor, app.matPath, app.matFile, app.arrayPos);
            msgbox('Re-registration of dynamic data is not yet supported')
            displayImages(app);
            set(app.busyTextPanel,'Visible','off');
        end

        % Callback function
        function redCheckbox_Callback(app, event)
            displayImages(app);
        end

        % Button pushed function: trimDataButton
        function trimDataButton_Callback(app, event)
            set(app.busyTextPanel,'Visible','on');
            drawnow;
            answer = questdlg(['Trim data by ignoring windows 1-' num2str(app.arrayPos) '?'],'Remove Windows?','Yes','No','Yes');
            figure(app.figureWindow);
            if strcmp(answer, 'Yes')
                dynData = app.dynData;
                params = app.params;
                excludeIdx = [dynData.([params.BaitChannel 'SpotData']).appearedInWindow] < app.arrayPos;
                excludedData = struct([params.BaitChannel 'SpotData'],[]);
                excludedData.([params.BaitChannel 'SpotData']) = dynData.([params.BaitChannel 'SpotData'])(excludeIdx);
                dynData.([params.BaitChannel 'SpotData'])(excludeIdx) = [];
                [dynData.BaitSpotCount,~] = size(dynData.BaitSpotData);
                dynData.([params.BaitChannel 'AppearanceFound']) = sum(cellfun(@isnumeric, {dynData.([params.BaitChannel 'SpotData']).appearTimeFrames})) ;
                for a = params.preyChNums
                    preyChannel = ['PreyCh' num2str(a)];
                    excludedData.([preyChannel 'SpotData']) = dynData.([preyChannel 'SpotData'])(excludeIdx);
                    dynData.([preyChannel 'SpotData'])(excludeIdx) = [];
                    dynData.([params.BaitChannel preyChannel 'CoAppearing']) = sum([ dynData.([params.BaitChannel 'SpotData']).(['appears_w_' preyChannel]) ], 'omitnan');
                end
                save([app.matPath filesep app.matFile],'dynData','params','excludedData');
                app.dynData = dynData;
                clear dynData params excludedData;
            end
            getFilterIndices(app);
            displayImages(app);
            set(app.busyTextPanel,'Visible','off');
        end

        % Callback function
        function LeftChannelButtonGroupSelectionChanged(app, event)
            displayImages(app);
        end

        % Callback function
        function CenterChannelButtonGroupSelectionChanged(app, event)
            displayImages(app);
        end

        % Value changing function: arrayPosSlider
        function arrayPosSliderValueChanging(app, event)
            changingValue = round(event.Value);
            if changingValue ~= app.arrayPos
                app.arrayPos = changingValue;
                
                % The following statement prevents dragging the slider to 1 when showing difference images
                if changingValue == 1 && get(app.DifferenceImagesButton,'Value')
                    app.arrayPos = 2;
                end
                
                displayImages(app);
            end
        end

        % Value changed function: arrayPosSlider
        function arrayPosSliderValueChanged(app, event)
            value = app.arrayPosSlider.Value;
            
            % The following statement prevents dragging the slider to 1 when showing difference images
            if value == 1 && get(app.DifferenceImagesButton,'Value')
                value = 2;
            end
            
            set(app.arrayPosSlider,'Value',round(value));
        end

        % Selection changed function: buttonPanel
        function buttonPanelSelectionChanged(app, event)
            displayImages(app);
        end

        % Window button down function: figureWindow
        function figureWindowWindowButtonDown(app, event)
            delete(findobj(app.mergedImagePanel,'type','images.roi.Point'));
            z=app.mergedImagePanel.CurrentPoint;
            x=z(1,1);  y=z(1,2);               
            %Determine if user clicked on UIAxes; if so, draw plot
            if x >= app.mergedImagePanel.XLim(1) & x <= app.mergedImagePanel.XLim(2) & y >= app.mergedImagePanel.YLim(1) & y <= app.mergedImagePanel.YLim(2)              
                 img_x = (x/app.mergedImagePanel.Position(3))*app.imgSize(1);
                 img_y = (y/app.mergedImagePanel.Position(4))*app.imgSize(2);
                 
                 %Find spot the user clicked
                 [spotLocations] = {app.activeBaitSpots.spotLocation}';
                 query = [img_x img_y];
                 match = find(cellfun(@(x) sum(abs(x-query))<5, spotLocations));
                 
                 if ~isempty(match)
                    %Make sure we only found one spot, correct if not - this is important for dense images
                    if length(match) > 1
                        [~,idx] = min(arrayfun(@(x) sum(abs(query - spotLocations{x})),match));
                        match = match(idx);
                    end
                     
                    %Generate the Fits for the New Trajectories
                    %Bait Channel
                    if isempty(app.activeBaitSpots(match).changepoints)
                        nChangepoints = 0;
                    else
                        [nChangepoints, ~] = size(app.activeBaitSpots(match).changepoints);
                    end
                    lastchangepoint = 1;
                    baitTraj = app.activeBaitSpots(match).intensityTrace;
                    baitFit = zeros(1,length(baitTraj));
                    for a = 1:nChangepoints+1
                        if a>nChangepoints
                            changepoint = length(baitTraj);
                        else
                            changepoint = app.activeBaitSpots(match).changepoints(a,1);
                        end
                        if changepoint == 0
                            continue
                        end
                        try
                            baitFit(lastchangepoint:changepoint) = app.activeBaitSpots(match).steplevels(a);
                        catch
                            baitFit(lastchangepoint:changepoint) = app.activeBaitSpots(match).steplevels(end); %Protects against having too few step levels because a changepoint was rejected
                        end
                        lastchangepoint = changepoint+1; %"+1" just keeps the segments from overlapping
                    end
                    %Prey Channel
                    if isempty(app.activePreySpots(match).changepoints)
                        nChangepoints = 0;
                    else
                        [nChangepoints, ~] = size(app.activePreySpots(match).changepoints);
                    end
                    lastchangepoint = 1;
                    preyTraj = app.activePreySpots(match).intensityTrace;
                    preyFit = zeros(1,length(preyTraj));
                    for a = 1:nChangepoints+1
                        if a>nChangepoints
                            changepoint = length(preyTraj);
                        else
                            changepoint = app.activePreySpots(match).changepoints(a,1);
                        end
                        if changepoint == 0
                            continue
                        end
                        try
                            preyFit(lastchangepoint:changepoint) = app.activePreySpots(match).steplevels(a);
                        catch
                            preyFit(lastchangepoint:changepoint) = app.activePreySpots(match).steplevels(end); %Protects against having too few step levels because a changepoint was rejected
                        end
                        lastchangepoint = changepoint+1; %"+1" just keeps the segments from overlapping
                    end                    
                                      
                    %Plot the New Trajectories
                    cla(app.traceAxes);
                    app.traceAxes.Box = 'off';
                    %yyaxis(app.generalUIAxes,'left')
                    app.traceAxes.YLabel.String = 'Fluorescence Intensity';
                    hold(app.traceAxes,'on')
                    xaxis = 1:length(baitTraj);
                    plot(app.traceAxes,xaxis,baitTraj,'Color',[0.25 1 0.25]); %For now, color choices assume Green is bait and Far Red is prey - could add a more complicated code to automatically choose colors later
                    plot(app.traceAxes,xaxis,baitFit,'Color',[0 1 0],'LineWidth',3);
                    xaxis = 1:length(preyTraj);
                    plot(app.traceAxes,xaxis,preyTraj,'Color',[1 0.25 1]); %For now, color choices assume Green is bait and Far Red is prey - could add a more complicated code to automatically choose colors later
                    plot(app.traceAxes,xaxis,preyFit,'Color',[1 0 1],'LineWidth',3); 
                    app.traceAxes.XLim = [0 max(length(baitTraj), length(preyTraj))];
                    hold(app.traceAxes,'off')
                    
                    %Position the plot
                    parent_x = app.mergedImagePanel.Position(1);
                    parent_y = app.mergedImagePanel.Position(2);
                    parent_height = app.mergedImagePanel.Position(4);
                    pos_x = parent_x + x - 400*app.scale - 5;
                    if(img_y < app.imgSize(2)/2)
                        pos_y = parent_y + parent_height - y - 300*app.scale - 5;
                    else
                        pos_y = parent_y + parent_height - y + 5;
                    end
                    set(app.tracePanel,'Visible','on', 'Position',[pos_x, pos_y, 400*app.scale, 300*app.scale]);
                 end
            end
        end

        % Value changed function: ignoreBlinkerCheckBox
        function ignoreBlinkerCheckBoxValueChanged(app, event)
            displayImages(app);
        end

        % Value changed function: ignoreLateCheckBox
        function ignoreLateCheckBoxValueChanged(app, event)
            displayImages(app);
        end

        % Value changed function: ignoreNonAppearingCheckBox
        function ignoreNonAppearingCheckBoxValueChanged(app, event)
            displayImages(app);
        end

        % Selection changed function: preyButtonPanel
        function preyButtonPanelSelectionChanged(app, event)
            if app.preyButton1.Value
                app.whichPreyCh = 1;
            elseif app.preyButton2.Value
                app.whichPreyCh = 2;
            elseif app.preyButton3.Value
                app.whichPreyCh = 3;
            else
                error('Could not get selected channel!');
            end
            
            displayImages(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figureWindow = uifigure('Visible', 'off');
            colormap(app.figureWindow, 'parula');
            app.figureWindow.Name = 'spotcount_inspector_GUI';
            app.figureWindow.WindowButtonDownFcn = createCallbackFcn(app, @figureWindowWindowButtonDown, true);
            app.figureWindow.WindowKeyPressFcn = createCallbackFcn(app, @figureWindow_WindowKeyPressFcn, true);
            app.figureWindow.Tag = 'figureWindow';
            app.greenImagePanel = uiaxes(app.figureWindow);
            app.greenImagePanel.XTick = [];
            app.greenImagePanel.YTick = [];
            app.greenImagePanel.Tag = 'greenImagePanel';
            app.redImagePanel = uiaxes(app.figureWindow);
            app.redImagePanel.XLim = [0 1];
            app.redImagePanel.YLim = [0 1];
            app.redImagePanel.XTick = [];
            app.redImagePanel.XTickLabel = {};
            app.redImagePanel.YTick = [];
            app.redImagePanel.YTickLabel = {};
            app.redImagePanel.Tag = 'redImagePanel';
            app.mergedImagePanel = uiaxes(app.figureWindow);
            app.mergedImagePanel.XTick = [];
            app.mergedImagePanel.YTick = [];
            app.mergedImagePanel.Tag = 'mergedImagePanel';
            app.positionText = uilabel(app.figureWindow);
            app.positionText.Tag = 'positionText';
            app.positionText.Text = 'Static Text';
            app.colocText = uilabel(app.figureWindow);
            app.colocText.Tag = 'colocText';
            app.colocText.Text = 'Static Text';
            app.greenText = uilabel(app.figureWindow);
            app.greenText.Tag = 'greenText';
            app.greenText.Text = 'Static Text';
            app.loadButton = uibutton(app.figureWindow, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButton_Callback, true);
            app.loadButton.Tag = 'loadButton';
            app.loadButton.Text = 'Load New Dataset';
            app.artifactPanel = uipanel(app.figureWindow);
            app.artifactPanel.Title = 'Artifact Removal';
            app.artifactPanel.Tag = 'artifactPanel';
            app.drawROIbutton = uibutton(app.artifactPanel, 'push');
            app.drawROIbutton.ButtonPushedFcn = createCallbackFcn(app, @drawROIbutton_Callback, true);
            app.drawROIbutton.Tag = 'drawROIbutton';
            app.drawROIbutton.Visible = 'off';
            app.drawROIbutton.Tooltip = 'Click to select a region of the merged image';
            app.drawROIbutton.Text = 'Draw Region';
            app.excludeROIbutton = uibutton(app.artifactPanel, 'push');
            app.excludeROIbutton.ButtonPushedFcn = createCallbackFcn(app, @excludeROIbutton_Callback, true);
            app.excludeROIbutton.Tag = 'excludeROIbutton';
            app.excludeROIbutton.Enable = 'off';
            app.excludeROIbutton.Visible = 'off';
            app.excludeROIbutton.Tooltip = 'Click to exclude the selected region from the analysis';
            app.excludeROIbutton.Text = 'Exclude Region';
            app.defineROIbutton = uibutton(app.artifactPanel, 'push');
            app.defineROIbutton.ButtonPushedFcn = createCallbackFcn(app, @defineROIbutton_Callback, true);
            app.defineROIbutton.Tag = 'defineROIbutton';
            app.defineROIbutton.Enable = 'off';
            app.defineROIbutton.Visible = 'off';
            app.defineROIbutton.Tooltip = 'Click to keep the selected region and exclude the rest of this image from the analysis';
            app.defineROIbutton.Text = 'Define ROI';
            app.reRegisterButton = uibutton(app.artifactPanel, 'push');
            app.reRegisterButton.ButtonPushedFcn = createCallbackFcn(app, @reRegisterButton_Callback, true);
            app.reRegisterButton.Tag = 'reRegisterButton';
            app.reRegisterButton.Tooltip = 'Click to re-register this sample using the currently-displayed image';
            app.reRegisterButton.Text = 'Redo Registration';
            app.trimDataButton = uibutton(app.artifactPanel, 'push');
            app.trimDataButton.ButtonPushedFcn = createCallbackFcn(app, @trimDataButton_Callback, true);
            app.trimDataButton.Tag = 'tossImageButton';
            app.trimDataButton.Tooltip = {'Ignore data prior to the current image window'};
            app.trimDataButton.Text = 'Trim Data';
            app.uibuttongroup1 = uibuttongroup(app.artifactPanel);
            app.uibuttongroup1.Visible = 'off';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.text12 = uilabel(app.uibuttongroup1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Apply region to:';
            app.roiThisImageButton = uiradiobutton(app.uibuttongroup1);
            app.roiThisImageButton.Tag = 'roiThisImageButton';
            app.roiThisImageButton.Text = 'This image';
            app.roiThisImageButton.Value = true;
            app.roiAllImagesButton = uiradiobutton(app.uibuttongroup1);
            app.roiAllImagesButton.Tag = 'roiAllImagesButton';
            app.roiAllImagesButton.Text = 'All images';
            app.busyTextPanel = uipanel(app.figureWindow);
            app.busyTextPanel.Tag = 'busyTextPanel';
            app.busyText = uilabel(app.busyTextPanel);
            app.busyText.Tag = 'busyText';
            app.busyText.Text = 'Working...';
            app.arrayPosSlider = uislider(app.figureWindow);
            app.arrayPosSlider.ValueChangedFcn = createCallbackFcn(app, @arrayPosSliderValueChanged, true);
            app.arrayPosSlider.ValueChangingFcn = createCallbackFcn(app, @arrayPosSliderValueChanging, true);
            app.buttonPanel = uibuttongroup(app.figureWindow);
            app.buttonPanel.SelectionChangedFcn = createCallbackFcn(app, @buttonPanelSelectionChanged, true);
            app.buttonPanel.Title = 'Show:';
            app.AveragedImagesButton = uiradiobutton(app.buttonPanel);
            app.AveragedImagesButton.Text = 'Averaged Images';
            app.AveragedImagesButton.Value = true;
            app.DifferenceImagesButton = uiradiobutton(app.buttonPanel);
            app.DifferenceImagesButton.Text = 'Difference Images';
            app.greenCheckbox = uicheckbox(app.buttonPanel);
            app.greenCheckbox.ValueChangedFcn = createCallbackFcn(app, @greenCheckbox_Callback, true);
            app.greenCheckbox.Tag = 'greenCheckbox';
            app.greenCheckbox.Text = 'Bait Spots';
            app.greenCheckbox.Value = true;
            app.colocCheckbox = uicheckbox(app.buttonPanel);
            app.colocCheckbox.ValueChangedFcn = createCallbackFcn(app, @colocCheckbox_Callback, true);
            app.colocCheckbox.Tag = 'colocCheckbox';
            app.colocCheckbox.Text = 'Co-appearing Spots';
            app.colocCheckbox.Value = true;
            app.ignoreBlinkerCheckBox = uicheckbox(app.buttonPanel);
            app.ignoreBlinkerCheckBox.ValueChangedFcn = createCallbackFcn(app, @ignoreBlinkerCheckBoxValueChanged, true);
            app.ignoreBlinkerCheckBox.Text = 'Ignore Blinking Spots';
            app.ignoreBlinkerCheckBox.Value = true;
            app.ignoreLateCheckBox = uicheckbox(app.buttonPanel);
            app.ignoreLateCheckBox.ValueChangedFcn = createCallbackFcn(app, @ignoreLateCheckBoxValueChanged, true);
            app.ignoreLateCheckBox.Text = 'Ignore Late-appearing Spots';
            app.ignoreLateCheckBox.Value = true;
            app.ignoreNonAppearingCheckBox = uicheckbox(app.buttonPanel);
            app.ignoreNonAppearingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ignoreNonAppearingCheckBoxValueChanged, true);
            app.ignoreNonAppearingCheckBox.Text = 'Ignore Spots Without Appearance';
            app.ignoreNonAppearingCheckBox.Value = true;
            app.tracePanel = uipanel(app.figureWindow);
            app.tracePanel.Visible = 'off';
            app.traceAxes = uiaxes(app.tracePanel);
            ylabel(app.traceAxes, 'Fluorescence Intensity')
            app.esc_text = uilabel(app.tracePanel);
            app.esc_text.Tag = 'colocText';
            app.esc_text.Text = 'Press Esc. to close plot';
            app.preyButtonPanel = uibuttongroup(app.figureWindow);
            app.preyButtonPanel.SelectionChangedFcn = createCallbackFcn(app, @preyButtonPanelSelectionChanged, true);
            app.preyButtonPanel.Title = 'Prey Channel';
            app.preyButton1 = uiradiobutton(app.preyButtonPanel);
            app.preyButton1.Text = 'Prey channel 1';
            app.preyButton1.Value = true;
            app.preyButton2 = uiradiobutton(app.preyButtonPanel);
            app.preyButton2.Text = 'Prey channel 2';
            app.preyButton3 = uiradiobutton(app.preyButtonPanel);
            app.preyButton3.Text = 'Prey channel 3';
            app.redText = uilabel(app.figureWindow);
            app.redText.Tag = 'greenText';
            app.redText.Text = 'Static Text';
            % Show the figure after all components are created
            app.figureWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coAppearance_inspector_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figureWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)colocalization_inspector_GUI_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figureWindow)
        end
    end
end