classdef trace_inspector_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figureWindow                  matlab.ui.Figure
        DataFilteringPanel            matlab.ui.container.Panel
        CoAppDropDown                 matlab.ui.control.DropDown
        BaitPhotobleachingStepsLabel  matlab.ui.control.Label
        MaxSpinner                    matlab.ui.control.Spinner
        MaxSpinnerLabel               matlab.ui.control.Label
        MinSpinner                    matlab.ui.control.Spinner
        MinSpinnerLabel               matlab.ui.control.Label
        saveButton                    matlab.ui.control.Button
        infoButton                    matlab.ui.control.Button
        instructionText               matlab.ui.control.Label
        stepCountText                 matlab.ui.control.Label
        generalUIAxes                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        arrayPos % Where we are in the image sequence
        dumped
        
        % Properties of data
        matPath %Path and
        matFile %File where the data live
        mode %Type of data - 'static' or 'dynamic'
        spotData % Intial copy of data from a selected channel of SpotData found in the gridData struture of selected matfile 
        activeSpotData % The copy of spotData in use depending on the selection from the countMenu
        nTraces % The number of spots
        tmax % Length of a single intensity trace for a given spot 
        BaitChannel % For dynamic data, the bait channel
        PreyChNums % For dynamic data, which channel numbers are prey
        
        % Display properties
        screenSize
        showChangepoints % Stores whether all changepoints or only accepted changepoints in fluoresecent intensity to qualify for a step are shown
        
    end
    
    methods (Access = private)
        function updateSelectedData(app)
            switch app.mode
                case 'static'
                    counts = {app.spotData.nSteps};
                    
                    %Find spots that match the selection
                    selection = app.CoAppDropDown.Value;
                    if strcmp(selection, 'All Traces')
                        index = true(1,app.nTraces);
                        set(app.MinSpinner,'Value',0);
                        set(app.MinSpinner,'Enable','off');
                        set(app.MaxSpinner,'Value', app.MaxSpinner.Limits(2));
                        set(app.MaxSpinner,'Enable','off');
                    elseif strcmp(selection, 'Rejected Traces')
                        index = strcmp(counts,'Rejected');
                        set(app.MinSpinner,'Enable','off');
                        set(app.MaxSpinner,'Enable','off');
                    else 
                        %Find rejected spots
                        rejIdx = ~cellfun(@isnumeric,counts);
                        %Set rejected spots to 0
                        [counts{rejIdx}] = deal(0); 
                        %Find spots with the right number of steps
                        selIdx = cellfun(@(x) x>=app.MinSpinner.Value & x<=app.MaxSpinner.Value, counts);
                        index = ~rejIdx & selIdx;
                        set(app.MinSpinner,'Enable','on');
                        set(app.MaxSpinner,'Enable','on');
                    end
                    app.activeSpotData = app.spotData(index,:);
                    app.nTraces = length(app.activeSpotData);
                    app.tmax = length(app.spotData(1).intensityTrace);
                    app.arrayPos = 1;


                case 'dynamic'
                    %Figure out which traces match the CoAppearance Selection
                    selection = app.CoAppDropDown.Value;
                    coAppIndex = true(1,length(app.spotData));
                    if strcmp(selection,'All Traces')
                        % No need to do anything
                    elseif strcmp(selection, 'Non-Co-Appearing Traces')
                        for f = app.PreyChNums                        
                            coAppears = {app.spotData.(app.BaitChannel).(['appears_w_PreyCh' num2str(f) ]) };
                            NaNs = cellfun(@(x) isempty(x) || isnan(x), coAppears);
                            [coAppears{NaNs}] = deal(true); %Set NaN values to true so that when the index is inverted on the next line, they'll be false and won't be displayed
                            coAppIndex = coAppIndex & ~cell2mat(coAppears); %Invert index!
                        end
                    elseif strcmp(selection, 'Traces with Bait Appearance Not Found')
                        appearTime = {app.spotData.(app.BaitChannel).appearTimeFrames};
                        coAppIndex = ~cellfun(@isnumeric,appearTime);
                    else %Co-Appearing Traces
                        ChSelection = selection(end);
                        coAppIndex = true(1, length(app.spotData.(app.BaitChannel)));
                        coAppears = {app.spotData.(app.BaitChannel).(['appears_w_PreyCh' num2str(ChSelection)]) };                            
                        NaNs = cellfun(@isnan,coAppears);
                        [coAppears{NaNs}] = deal(false); %Set NaN values to false so they won't be displayed
                        coAppIndex = coAppIndex & cell2mat(coAppears);
                    end
        
                    %Figure out which traces have the right number of steps
                    counts = {app.spotData.(app.BaitChannel).nFluors};
                    NanIndex = cellfun(@isnan,counts);
                    [counts{NanIndex}] = deal(0);
                    nStepsIndex = cell2mat(cellfun(@(x) x>=app.MinSpinner.Value & x<=app.MaxSpinner.Value, counts,'UniformOutput',false));
                    
                    %Update active traces to the selection
                    index = coAppIndex & nStepsIndex; 
                    app.activeSpotData.(app.BaitChannel) = app.spotData.(app.BaitChannel)(index);
                    for f = app.PreyChNums
                        app.activeSpotData.(['PreyCh' num2str(f)]) = app.spotData.(['PreyCh' num2str(f)])(index);
                    end
                    app.nTraces = length(app.activeSpotData.(app.BaitChannel));
                    %app.tmax = length(app.spotData(1).intensityTrace);
                    app.arrayPos = 1;
            end
        end
        
        function updatePlot(app, b)
            if b==0 || b > app.nTraces
                b = 1;
            end
            
            switch app.mode
                case 'static'
            
                    %Generate the Fit for the New Trajectory
                    if isempty(app.activeSpotData(b).changepoints)
                        nChangepoints = 0;
                    else
                        [nChangepoints, ~] = size(app.activeSpotData(b).changepoints);
                    end
                    lastchangepoint = 1;
                    traj = app.activeSpotData(b).intensityTrace;
                    fit = zeros(1,app.tmax);
                    for a = 1:nChangepoints+1
                        if a>nChangepoints
                            changepoint = app.tmax;
                        else
                            changepoint = app.activeSpotData(b).changepoints(a,1);
                        end
                        if changepoint == 0
                            continue
                        end
                        try
                            fit(lastchangepoint:changepoint) = app.activeSpotData(b).steplevels(a);
                        catch
                            fit(lastchangepoint:changepoint) = app.activeSpotData(b).steplevels(end); %Protects against having too few step levels because a changepoint was rejected
                        end
                        lastchangepoint = changepoint+1; %"+1" just keeps the segments from overlapping
                    end
                    
                    bars = zeros(app.tmax,1);
                    if ~isempty(app.activeSpotData(b).(app.showChangepoints))
                        bars(app.activeSpotData(b).(app.showChangepoints)(:,1)) = app.activeSpotData(b).(app.showChangepoints)(:,2);
                    end
                    
                    %Plot the New Trajectory
                    cla(app.generalUIAxes);
                    yyaxis(app.generalUIAxes,'right')
                    xaxis = 1:length(traj);
                    if nChangepoints > 0
                        bar(app.generalUIAxes,xaxis,bars,'FaceColor',[0 0.5 0],'EdgeColor','none','Barwidth',0.5);
                    else
                        cla(app.generalUIAxes);
                    end 
                    app.generalUIAxes.YLabel.String = 'Changepoint Log Odds';
                    app.generalUIAxes.YColor = [0 0.5 0];
                    app.generalUIAxes.XLim = [0 app.tmax];
                    app.generalUIAxes.Box = 'off';
                    yyaxis(app.generalUIAxes,'left')
                    app.generalUIAxes.YLabel.String = 'Fluorescence Intensity';
                    hold(app.generalUIAxes,'on')
                    plot(app.generalUIAxes,xaxis,traj)
                    plot(app.generalUIAxes,xaxis,fit,'-r','LineWidth',3);
                    hold(app.generalUIAxes,'off')
        
                    if ischar(app.activeSpotData(b).nSteps)
                        set(app.stepCountText,'Text','Trace Rejected')
                    elseif app.activeSpotData(b).nSteps == 1
                        set(app.stepCountText,'Text','1 Step');
                    else
                        set(app.stepCountText,'Text',[num2str(app.activeSpotData(b).nSteps), ' Steps']);
                    end
                    
                case 'dynamic'
                    %Prepare axes
                    cla(app.generalUIAxes);
                    app.generalUIAxes.Box = 'off';
                    %yyaxis(app.generalUIAxes,'left')
                    app.generalUIAxes.YLabel.String = 'Fluorescence Intensity';
                    hold(app.generalUIAxes,'on')

                    %Generate and plot the Fits for the New Trajectories
                    %Bait Channel
                    if isempty(app.activeSpotData.(app.BaitChannel)(b).changepoints)
                        nChangepoints = 0;
                    else
                        [nChangepoints, ~] = size(app.activeSpotData.(app.BaitChannel)(b).changepoints);
                    end
                    lastchangepoint = 1;
                    baitTraj = app.activeSpotData.(app.BaitChannel)(b).intensityTrace;
                    plot(app.generalUIAxes,1:length(baitTraj),baitTraj,'Color',[0.25 1 0.25]); %For now, color choices assume Green is bait and Far Red is prey - could add a more complicated code to automatically choose colors later
                    baitFit = zeros(1,length(baitTraj));
                    if ~strcmp(app.activeSpotData.(app.BaitChannel)(b).appearTimeFrames,'Analysis Failed')
                        for a = 1:nChangepoints+1
                            if a>nChangepoints
                                changepoint = length(baitTraj);
                            else
                                changepoint = app.activeSpotData.(app.BaitChannel)(b).changepoints(a,1);
                            end
                            if changepoint == 0
                                continue
                            end
                            try
                                baitFit(lastchangepoint:changepoint) = app.activeSpotData.(app.BaitChannel)(b).steplevels(a);
                            catch
                                baitFit(lastchangepoint:changepoint) = app.activeSpotData.(app.BaitChannel)(b).steplevels(end); %Protects against having too few step levels because a changepoint was rejected
                            end
                            lastchangepoint = changepoint+1; %"+1" just keeps the segments from overlapping
                        end
                        plot(app.generalUIAxes,1:length(baitTraj),baitFit,'Color',[0 1 0],'LineWidth',3);
                    end

                    %Prey Channel(s)
                    preyTraj = [];
                    for c = app.PreyChNums
                        PreyChannel = ['PreyCh' num2str(c)]; %This is just for code readability
                        if isempty(app.activeSpotData.(PreyChannel)(b).changepoints)
                            nChangepoints = 0;
                        else
                            [nChangepoints, ~] = size(app.activeSpotData.(PreyChannel)(b).changepoints);
                        end
                        lastchangepoint = 1;
                        preyTraj = app.activeSpotData.(PreyChannel)(b).intensityTrace;
                        preyFit = zeros(1,length(preyTraj));
                        for a = 1:nChangepoints+1
                            if a>nChangepoints
                                changepoint = length(preyTraj);
                            else
                                changepoint = app.activeSpotData.(PreyChannel)(b).changepoints(a,1);
                            end
                            if changepoint == 0
                                continue
                            end
                            try
                                preyFit(lastchangepoint:changepoint) = app.activeSpotData.(PreyChannel)(b).steplevels(a);
                            catch
                                preyFit(lastchangepoint:changepoint) = app.activeSpotData.(PreyChannel)(b).steplevels(end); %Protects against having too few step levels because a changepoint was rejected
                            end
                            lastchangepoint = changepoint+1; %"+1" just keeps the segments from overlapping
                        end
                        %For now, color choices assume 2-color data is Green/FarRed and 3-color is Green/Red/FarRed. 
                        %Could add a more complicated code to automatically choose colors later
                        if c == app.PreyChNums(end)
                            trajColor = [1 0.25 1]; 
                            fitColor = [1 0 1];
                        else
                            trajColor = [0.85 0.5 0.098];
                            fitColor = [0.85 0.325 0.098];
                        end
                        plot(app.generalUIAxes,1:length(preyTraj),preyTraj,'Color',trajColor); 
                        plot(app.generalUIAxes,1:length(preyTraj),preyFit,'Color',fitColor,'LineWidth',3);                    
                    end
                    app.generalUIAxes.XLim = [0 max(length(baitTraj), length(preyTraj))];
                    hold(app.generalUIAxes,'off')
                    
                    % Update descriptive text
                    description = '';
                    if isfield(app.activeSpotData.(app.BaitChannel), 'appearTimeFrames') && ~isnumeric(app.activeSpotData.(app.BaitChannel)(b).appearTimeFrames) ||... %Current format
                       isfield(app.activeSpotData.(app.BaitChannel), 'appearTime') && ~isnumeric(app.activeSpotData.(app.BaitChannel)(b).appearTime)    %Legacy format
                        if ~strcmp(app.activeSpotData.(app.BaitChannel)(b).appearTimeFrames,'Analysis Failed')
                            description = 'Analysis Failed!';
                        else
                            description = 'Bait protein appearance was not found';
                        end
                    else
                        if isfield(app.activeSpotData.(app.BaitChannel),'nFluors')
                            description = [num2str(app.activeSpotData.(app.BaitChannel)(b).nFluors) ' bait fluorophores' newline];
                        end
                        for d = app.PreyChNums
                            PreyChannel = ['PreyCh' num2str(d)]; %This is just for code readability
                            if app.activeSpotData.(app.BaitChannel)(b).(['appears_w_' PreyChannel]) 
                                description = [description num2str(app.activeSpotData.(PreyChannel)(b).nFluors) ' ' PreyChannel ' fluorophores' newline];
                            else
                                description = [description '0 ' PreyChannel ' fluorophores' newline];
                            end
                        end
                    end
                    set(app.stepCountText,'Text',description)
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function trace_inspector_GUI_OpeningFcn(app, varargin)
        
           app.figureWindow.Visible = 'off';
            %Get the data from the automated processing
            if ~isempty(app.matPath)
                startPath = app.matPath;
            else
                startPath = pwd;
            end
            dummyFig = figure;
            set(dummyFig,'Visible','off');
            [app.matFile, app.matPath] = uigetfile('*.mat','Choose a .mat file with dynamic data from the automated analysis',startPath);
            delete(dummyFig);
            app.figureWindow.Visible = 'on';
            load([app.matPath filesep app.matFile]);
            
            %Check what kind of data we have
            if exist('gridData','var')
                app.mode = 'static';
            elseif exist('dynData','var')
                app.mode = 'dynamic';
            else
                errordlg('This file does not appear to contain SiMPull data');
            end
         
             %Set up figure window
            app.screenSize = get(0,'ScreenSize');
            figureWidth = 650;
            figureHeight = 650;
            set(app.figureWindow,'Position',[(app.screenSize(3)-figureWidth)/2 app.screenSize(4)-figureHeight-100 figureWidth figureHeight]);
            set(app.instructionText,'Position',[420 564 154 40]);
            set(app.infoButton,'Position',[420 477 144 38]);
            set(app.saveButton,'Position',[420 438 144 38]);
            set(app.DataFilteringPanel,'Position',[45 455 186 138]);
            set(app.generalUIAxes,'Position',[55 5 500 400]);
            set(app.stepCountText,'Position',[205 400 300 40]);
            app.arrayPos = 1;
            
            %Prepare Data
            if strcmp(app.mode,'static')
                %Prepare Static Data
                if nChannels > 1
                    channel = questdlg('Multi-Channel Data Found. Select Channel to Display',...
                                        'Select Channel',...
                                         channels{:},channels{1});
                else
                    channel = channels{1};
                end
            
                if ~isfield(statsByColor, [channel 'StepHist'])
                    msgbox('This program requires photobleaching step data.  Please run the step counter first');
                    return
                end
                
                for c = 1:length(gridData)
                    index2(c) = isfield(gridData(c).([channel 'SpotData']),'nSteps');
                end
                spotData = {gridData(index2).([channel 'SpotData'])};
                app.spotData = vertcat(spotData{ cellfun(@length, spotData) > 1 }); % The argument inside {} tosses images with no spots
                clear gridData spotData
                app.activeSpotData = app.spotData;
                app.nTraces = length(app.spotData);
                app.tmax = length(app.spotData(1).intensityTrace);
                
                %Test whether step data has been filtered (required for displaying data processed with older versions)
                if ~isfield(app.activeSpotData(1), 'allchangepoints')
                    app.showChangepoints = 'changepoints';
                    %set(app.allChangepointsButton, 'Enable', 'off');
                    %set(app.acceptedChangepointsButton, 'Enable', 'off');
                else
                    app.showChangepoints = 'allchangepoints';
                end
                
                %Set up menu for trace selection
                set(app.CoAppDropDown,'Items',{'All Traces','Accepted Traces','Rejected Traces'},...
                                  'Value', 'All Traces');


                %Set up spinners for trace selection
                idx3 = cellfun(@isnumeric,{app.spotData.nSteps});
                maxSize = max(cell2mat({app.spotData(idx3).nSteps}));
                if  maxSize > 1
                    if app.MaxSpinner.Limits(2) < maxSize
                        app.MaxSpinner.Limits(2) = maxSize;
                        app.MaxSpinner.Value = app.MaxSpinner.Limits(2);
                    end
                    app.MinSpinner.Limits = app.MaxSpinner.Limits;
                else
                    app.MinSpinner.Enable = false;
                    app.MaxSpinner.Value = 1;
                    app.MaxSpinner.Enable = false;
                end
 
            elseif strcmp(app.mode,'dynamic')
                %Prepare Dynamic Data
                app.BaitChannel = params.BaitChannel;
                app.spotData.(app.BaitChannel) = dynData.([app.BaitChannel 'SpotData']);
                if isfield(params, 'preyChNums') %Current multicolor data format
                    app.PreyChNums = params.preyChNums;
                    for d = app.PreyChNums
                        app.spotData.(['PreyCh' num2str(d)]) = dynData.(['PreyCh' num2str(d) 'SpotData']);
                    end
                else %Legacy 2-color data
                    app.PreyChNums = 1;
                    app.spotData.PreyCh1 = dynData.PreySpotData;
                end
                clear dynData
                app.activeSpotData = app.spotData;
                app.nTraces = length(app.spotData.(app.BaitChannel));
                %app.tmax = length(app.spotData.(app.BaitChannel)(1).intensityTrace);
                
                %Set up menu for trace selection
                menuItems = {'All Traces'};
                for e = app.PreyChNums
                    menuItems{end+1} = ['Traces Co-Appearing with Prey Channel ' num2str(e)];
                end
                menuItems{end+1} = 'Traces with Bait Appearance Not Found';
                set(app.CoAppDropDown,'Items',menuItems,'Value','All Traces');

                if isfield(app.spotData.(app.BaitChannel),'nFluors')
                    maxSize = max(cell2mat({app.spotData.(app.BaitChannel).nFluors}));
                    if  maxSize > 1
                        if app.MaxSpinner.Limits(2) < maxSize
                            app.MaxSpinner.Limits(2) = maxSize;
                            app.MaxSpinner.Value = app.MaxSpinner.Limits(2);
                        end
                        app.MinSpinner.Limits = app.MaxSpinner.Limits;
                    else
                        app.MinSpinner.Enable = false;
                        app.MaxSpinner.Value = 1;
                        app.MaxSpinner.Enable = false;
                    end
                else
                    app.MinSpinner.Enable = false;
                    app.MaxSpinner.Value = 1;
                    app.MaxSpinner.Enable = false;
                end
            end
            
            %Plot the First Trajectory
            b = 1;
            updatePlot(app, b);
            
            %Set initial count of trajectories dumped
            app.dumped = 1;
            
        end

        % Callback function: figureWindow, figureWindow
        function figureWindow_WindowKeyPressFcn(app, event)

            %Decide what to do
            if isempty(app.activeSpotData)
                return
            end
            
            b = app.arrayPos;
            key = event.Key;
            
            switch key
                case 'n'
                    b=b+1;
                    if b > app.nTraces
                        warndlg('Reached end of data, starting over from beginning')
                        b = 1;
                    end
                       
                case 'p'
                    b=b-1;
                    if b < 1
                        warndlg('Reached beginning of data, going to end')
                        b = app.nTraces;
                    end
                case 'r'
                    b=round(rand(1)*app.nTraces);
            end
            
            app.arrayPos = b;
            updatePlot(app, b);
            
        end

        % Button pushed function: infoButton
        function infoButton_Callback(app, event)

            b = app.arrayPos;
            switch app.mode
                case 'static'
                    app.activeSpotData(b)
                case 'dynamic'
                    disp('Bait trace data:')
                    app.activeSpotData.(app.BaitChannel)(b)
                    for f = app.PreyChNums
                        disp(['Prey Channel ' num2str(f) ' trace data:'])
                        app.activeSpotData.(['PreyCh' num2str(f)])(b)
                    end
            end
            
        end

        % Button pushed function: saveButton
        function saveButton_Callback(app, event)

            figureWidth = 650;
            figureHeight = 500;
            newfig1 = figure('Position',[(app.screenSize(3)-figureWidth)/2 app.screenSize(4)-figureHeight-100 figureWidth figureHeight],'Visible','off');
            
            [outfile,outpath] = uiputfile('plot.pdf','Save File Name');
            if verLessThan('matlab','9.8')
                export_fig([outpath filesep outfile],newfig1,'-pdf','-png','-transparent');
            else
                exportgraphics(app.generalUIAxes,[outpath filesep outfile],'ContentType','vector');
            end
            close(newfig1);
        end

        % Value changed function: MinSpinner
        function MinSpinnerValueChanged(app, event)
            if app.MinSpinner.Value > app.MaxSpinner.Value
                app.MaxSpinner.Value = app.MinSpinner.Value;
            end

            %Select Correct Data
            updateSelectedData(app);

            %Update plot
            if app.nTraces > 0
                b = 1;
                updatePlot(app, b);
            else
                cla(app.generalUIAxes);
                set(app.stepCountText,'Text','No Trajectories Match Selection');
            end            
        end

        % Value changed function: MaxSpinner
        function MaxSpinnerValueChanged(app, event)
            if app.MaxSpinner.Value < app.MinSpinner.Value
                app.MinSpinner.Value = app.MaxSpinner.Value;
            end

            %Select Correct Data
            updateSelectedData(app);

            %Update plot
            if app.nTraces > 0
                b = 1;
                updatePlot(app, b);
            else
                cla(app.generalUIAxes);
                set(app.stepCountText,'Text','No Trajectories Match Selection');
            end
        end

        % Value changed function: CoAppDropDown
        function CoAppDropDownValueChanged(app, event)
            %Select Correct Data
            updateSelectedData(app);

            %Update plot
            if app.nTraces > 0
                b = 1;
                updatePlot(app, b);
            else
                cla(app.generalUIAxes);
                set(app.stepCountText,'Text','No Trajectories Match Selection');
            end                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figureWindow = uifigure('Visible', 'off');
            colormap(app.figureWindow, 'parula');
            app.figureWindow.Name = 'trace_inspector_GUI';
            app.figureWindow.WindowKeyPressFcn = createCallbackFcn(app, @figureWindow_WindowKeyPressFcn, true);
            app.figureWindow.KeyPressFcn = createCallbackFcn(app, @figureWindow_WindowKeyPressFcn, true);
            app.figureWindow.Tag = 'figureWindow';
            app.generalUIAxes = uiaxes(app.figureWindow);
            ylabel(app.generalUIAxes, 'Fluorescence Intensity')
            app.stepCountText = uilabel(app.figureWindow);
            app.stepCountText.Tag = 'stepCountText';
            app.stepCountText.Text = 'Trace Rejected';
            app.instructionText = uilabel(app.figureWindow);
            app.instructionText.Tag = 'instructionText';
            app.instructionText.Text = {'N to move to next trace'; 'P to move to previous trace'; 'R to move to a random trace'; ''};
            app.infoButton = uibutton(app.figureWindow, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButton_Callback, true);
            app.infoButton.Tag = 'infoButton';
            app.infoButton.Text = 'Display Trace Data';
            app.saveButton = uibutton(app.figureWindow, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton_Callback, true);
            app.saveButton.Tag = 'saveButton';
            app.saveButton.Text = 'Save Plot to Image';
            app.DataFilteringPanel = uipanel(app.figureWindow);
            app.DataFilteringPanel.Title = 'Data Filtering';
            app.MinSpinnerLabel = uilabel(app.DataFilteringPanel);
            app.MinSpinnerLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.DataFilteringPanel);
            app.MinSpinner.Limits = [0 Inf];
            app.MinSpinner.RoundFractionalValues = 'on';
            app.MinSpinner.ValueChangedFcn = createCallbackFcn(app, @MinSpinnerValueChanged, true);
            app.MaxSpinnerLabel = uilabel(app.DataFilteringPanel);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.DataFilteringPanel);
            app.MaxSpinner.Limits = [0 2];
            app.MaxSpinner.RoundFractionalValues = 'on';
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxSpinnerValueChanged, true);
            app.MaxSpinner.Value = 1;
            app.BaitPhotobleachingStepsLabel = uilabel(app.DataFilteringPanel);
            app.BaitPhotobleachingStepsLabel.Text = 'Bait Photobleaching Steps';
            app.CoAppDropDown = uidropdown(app.DataFilteringPanel);
            app.CoAppDropDown.ValueChangedFcn = createCallbackFcn(app, @CoAppDropDownValueChanged, true);
            % Show the figure after all components are created
            app.figureWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace_inspector_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figureWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)trace_inspector_GUI_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figureWindow)
        end
    end
end