classdef CoApp_vs_Time_plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        YAxisBehaviorButtonGroup      matlab.ui.container.ButtonGroup
        FocusonAverageButton          matlab.ui.control.RadioButton
        ShowAllDataButton             matlab.ui.control.RadioButton
        PreyChannelSelectionPanel     matlab.ui.container.Panel
        PreyCheckBox3                 matlab.ui.control.CheckBox
        PreyCheckBox2                 matlab.ui.control.CheckBox
        PreyCheckBox1                 matlab.ui.control.CheckBox
        DataSelectionPanel            matlab.ui.container.Panel
        DatasetTable                  matlab.ui.control.Table
        TrendlinesCheckBox            matlab.ui.control.CheckBox
        WeightedAverageCheckBox       matlab.ui.control.CheckBox
        SamplesCheckBox               matlab.ui.control.CheckBox
        LoadNewDataButton             matlab.ui.control.Button
        DataFilteringPanel            matlab.ui.container.Panel
        FilterKnownArtifactsCheckBox  matlab.ui.control.CheckBox
        ExcludeDenseDataCheckBox      matlab.ui.control.CheckBox
        ExcludeSparseDataCheckBox     matlab.ui.control.CheckBox
        BaitPhotobleachingStepsLabel  matlab.ui.control.Label
        MaxSpinner                    matlab.ui.control.Spinner
        MaxSpinnerLabel               matlab.ui.control.Label
        MinSpinner                    matlab.ui.control.Spinner
        MinLabel                      matlab.ui.control.Label
        ExponentialDecayFitPanel      matlab.ui.container.Panel
        StartTimeSpinner              matlab.ui.control.Spinner
        StartTimeSpinnerLabel         matlab.ui.control.Label
        EndTimeSpinner                matlab.ui.control.Spinner
        EndTimeSpinnerLabel           matlab.ui.control.Label
        PerformFitButton              matlab.ui.control.Button
        ClearFitsButton               matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        XmaxSpinner                   matlab.ui.control.Spinner
        XLimitsSpinnerLabel           matlab.ui.control.Label
        XminSpinner                   matlab.ui.control.Spinner
        CopyPlottoFigureButton        matlab.ui.control.Button
        ExportPlottoImageButton       matlab.ui.control.Button
        ResidualAxes                  matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        matPath % Location of data
        allMatFiles % All of the files to be considered in this analysis
        activeMatFiles % Files we are currently working with
        
        params
        trendWindow = 10; % Adjustable parameter for how many windows will be averaged for the trendline
        
        lowDensity
        highDensity
        totalCounted = struct();
        totalCoApp = struct();
        filteredCounted = struct();
        filteredCoApp = struct();
        x
        pctCoApp
        pooledPctCoApp
        
        % Handles for plotted data
        dataPlots = {}; % Holds all plotted raw data
        fitCurves = {}; % Holds exponential fit curves
        residCurves     % Holds fit residuals
        
        colors
    end
    
    methods (Access = private)
        
        function app = loadData(app)
            %Get the data from the automated processing
            if ~isempty(app.matPath)
                startPath = app.matPath;
            else
                startPath = pwd;
            end
            app.allMatFiles = uipickfiles('Prompt','Select data files or folders to analyze','Type',{'*.mat'},'FilterSpec',startPath);
            app.activeMatFiles = app.allMatFiles;
            % Set root directory
            slash = strfind(app.allMatFiles{1},filesep);
            app.matPath = app.allMatFiles{1}(1:slash(end));

            %% Load data
            fileBar = waitbar(0);
            app.PreyCheckBox1.Visible = true;
            app.PreyCheckBox2.Visible = true;
            app.PreyCheckBox3.Visible = true;
            app.MinSpinner.Visible = true;
            app.MinSpinner.Enable = true;
            app.MaxSpinner.Visible = true;
            app.MaxSpinner.Enable = true;
            app.MaxSpinner.Limits = [1,2];
            app.XmaxSpinner.Limits = [0, Inf];
            app.DatasetTable.Data = {'',false};
            tableWidth = app.DatasetTable.Position(3);
            app.DatasetTable.ColumnWidth = {tableWidth-25,25};
            app.DatasetTable.ColumnEditable = [false true];
            for a = 1:length(app.activeMatFiles)  
                % Get image name and add to selection pane
                slash = strfind(app.activeMatFiles{a},filesep);
                fileName = app.activeMatFiles{a}(slash(end)+1:end); 
                app.DatasetTable.Data(a,:) = {fileName,true};

                % Get Directory
                if isfolder(app.activeMatFiles{a})
                    fileName = [fileName '.mat'];
                    expDir = app.activeMatFiles{a};
                    if ~isfile([expDir filesep fileName])
                        warndlg(['No .mat file found for selected folder ' expDir]);
                        continue
                    end
                else
                    expDir = app.activeMatFiles{a}(1:slash(end));
                end

                % Load data structure
                load([expDir filesep fileName],'dynData','params');
                app.params = params; clear params;
                %Extract channel info - this is just for code readability
                BaitChannel = app.params.BaitChannel;
%                 if ~strcmp(app.params.DataType, 'Composite Data')
%                     PreyChannel = app.params.PreyChannel;
%                 end
                % Figure out what channels are present 
                app.PreyCheckBox1.Text = ['Channel ' num2str(app.params.preyChNums(1))];
                if length(app.params.preyChNums) >= 2
                    app.PreyCheckBox2.Text = ['Channel ' num2str(app.params.preyChNums(2))];
                else
                    app.PreyCheckBox2.Visible = false;
                end
                if length(app.params.preyChNums) >= 3
                    app.PreyCheckBox3.Text = ['Channel ' num2str(app.params.preyChNums(3))];
                else
                    app.PreyCheckBox3.Visible = false;
                end

                % Check if we have photobleaching data
                if isfield(dynData.BaitSpotData,'nFluors')
                    if app.MaxSpinner.Limits(2) < max([dynData.BaitSpotData.nFluors])
                        app.MaxSpinner.Limits(2) = max([dynData.BaitSpotData.nFluors]);
                        app.MaxSpinner.Value = app.MaxSpinner.Limits(2);
                    end
                    app.MinSpinner.Limits = app.MaxSpinner.Limits;
                else
                    app.MinSpinner.Visible = false;
                    app.MinSpinner.Enable = false;
                    app.MaxSpinner.Value = 1;
                    app.MinSpinner.Visible = false;
                    app.MaxSpinner.Enable = false;
                end               

                %% Calculate co-appearance vs. size vs. time
                waitbar((a-1)/length(app.activeMatFiles),fileBar,strrep(['Loading ' fileName],'_','\_'));
                % Convert appearance times from frames to seconds and shift based on the gap between lysis and acquisition
                if ~isnumeric(app.params.elapsedTime)
                    app.params.elapsedTime = 0;
                end
                for c = 1:length(dynData.BaitSpotData)
                    if ~isnumeric(dynData.BaitSpotData(c).appearTime)
                        continue
                    end
                    dynData.BaitSpotData(c).appearTime = dynData.BaitSpotData(c).appearTime * 0.05 + app.params.elapsedTime; %Hard-coded 50 ms exposure time - could be a paramter later if needed 
                end

                lastWindow = max(cell2mat({dynData.([BaitChannel 'SpotData']).appearedInWindow}));
                baitsForDensity = [];
                for b = 1:app.MaxSpinner.Value
                    % spotChoiceIdx selects spots with a given number of photobleaching steps
                    if isfield(dynData.BaitSpotData,'nFluors')
                        spotChoiceIdx = [dynData.BaitSpotData.nFluors] == b;
                    else
                        spotChoiceIdx = true (1, length(dynData.BaitSpotData));
                    end
                    spotChoiceIdx = spotChoiceIdx & cellfun(@isnumeric, {dynData.BaitSpotData.appearTime}); 
                    
                    % filterIdx eliminates blinking, late-appearing and short-dwell spots. 
                    % In a future version, these three filters could be applied separately. 
                    filterIdx = spotChoiceIdx & ~cellfun(@(x) isnumeric(x) && length(x)==1 && ~isnan(x) && x<2500, {dynData.BaitSpotData.nFramesSinceLastApp}); % Blinker filter
                    filterIdx = filterIdx & ~cellfun(@(x,y) isnumeric(x) && x > 50*(y+1), {dynData.BaitSpotData.appearTime}, {dynData.BaitSpotData.appearedInWindow}); % Late appearance filter
                    if ~isfield(dynData.BaitSpotData,'dwellTime')
                        [dynData, ~] = dwellTime_koff(0,{[expDir filesep fileName]},false, dynData, app.params);
                    end
                    filterIdx = filterIdx & ~cellfun(@(x) x<10, {dynData.BaitSpotData.dwellTime});

                    % Create structs for each prey's % co-appearance with bait
                    for s = 1:app.params.nChannels
                        if s == app.params.baitChNum
                            continue %Skip the bait channel
                        end
                        preyChannel = ['PreyCh' num2str(s)];
                        colocData = {dynData.([BaitChannel 'SpotData'])(spotChoiceIdx).(['appears_w_' preyChannel])};
                        filteredColocData = {dynData.([BaitChannel 'SpotData'])(filterIdx).(['appears_w_' preyChannel])};
                        baitsCounted = zeros(1,lastWindow);
                        coAppearing = zeros(1,lastWindow);
                        filtCounted = zeros(1,lastWindow);
                        filtCoAppearing = zeros(1,lastWindow);
                        for d = 1:lastWindow
                            lowerBound = (d-1) * app.params.window * 0.05;
                            upperBound = d * app.params.window * 0.05; %Hard-coded 50 ms exposure time - could be a paramter later if needed 
                            % Unfiltered data
                            index = cell2mat({dynData.([BaitChannel 'SpotData'])(spotChoiceIdx).appearTime}) > lowerBound & cell2mat({dynData.([BaitChannel 'SpotData'])(spotChoiceIdx).appearTime}) <= upperBound;
                            baitsCounted(d) = sum(~cellfun(@(x) isempty(x) || isnan(x), colocData(index)));
                            coAppearing(d) = sum(cellfun(@(x) ~isempty(x) && x==true, colocData(index)));
                            % Filtered data
                            index = cell2mat({dynData.([BaitChannel 'SpotData'])(filterIdx).appearTime}) > lowerBound & cell2mat({dynData.([BaitChannel 'SpotData'])(filterIdx).appearTime}) <= upperBound;
                            filtCounted(d) = sum(~cellfun(@(x) isempty(x) || isnan(x), filteredColocData(index)));
                            filtCoAppearing(d) = sum(cellfun(@(x) ~isempty(x) && x==true, filteredColocData(index)));
                        end  

                        % Put all the data together. Organization: 
                            % Each metric is saved in a structure with a field for each prey channel.
                            % Each field holds a cell array with one element for each particle size being analyzed (loop b).
                            % In each of these arrays, the horizontal dimension represents time (in windows), and the vertical dimension is for datasets (loop a).
                            % We also sum all of the baitsCounted for purposes of calculating density
                        if a == 1
                            app.totalCounted.(preyChannel){b} = baitsCounted;
                            app.totalCoApp.(preyChannel){b} = coAppearing;
                            app.filteredCounted.(preyChannel){b} = filtCounted;
                            app.filteredCoApp.(preyChannel){b} = filtCoAppearing;
                        else
                            app.totalCounted.(preyChannel){b}(a,1:length(baitsCounted)) = baitsCounted;
                            app.totalCoApp.(preyChannel){b}(a,1:length(coAppearing)) = coAppearing;
                            app.filteredCounted.(preyChannel){b}(a,1:length(baitsCounted)) = filtCounted;
                            app.filteredCoApp.(preyChannel){b}(a,1:length(coAppearing)) = filtCoAppearing;
                        end
                        if b == 1
                            baitsForDensity = baitsCounted;
                        else
                            baitsForDensity = baitsForDensity + baitsCounted;
                        end
                        clear coAppearing baitsCounted
                    end
                end
                % Add information for filtering low- and high-density data
                lowThreshold = (app.params.imgArea / 1e6) * 0.005; %0.005 molecules / um^2 is the lowest level where measurements are reliable. 
                app.lowDensity(a,1:length(baitsForDensity)) = baitsForDensity < lowThreshold;
                highThreshold = (app.params.imgArea / 1e6) * 0.8; %0.8 molecules / um^2 is the highest level where measurements are reliable. 
                app.highDensity(a,1:length(baitsForDensity)) = baitsForDensity > highThreshold;
            end
            close(fileBar)
        end

        function displayData(app)
            % Clear existing plots
            for a = 1:length(app.dataPlots)
                delete(app.dataPlots{a});
            end
            app.dataPlots = {};
            % Apply density filters, if applicable
            if app.ExcludeSparseDataCheckBox.Value
                densityIdx = ~app.lowDensity;
            else
                densityIdx = true(size(app.lowDensity));
            end
            if app.ExcludeDenseDataCheckBox.Value
                densityIdx = densityIdx & ~app.highDensity;
            end
            % Condense data and Plot
            app.colors = lines(app.params.nChannels);
            for s = 1:app.params.nChannels
                chIndex = find(app.params.preyChNums == s);
                if isempty(chIndex) || ~app.(['PreyCheckBox' num2str(chIndex)]).Value
                    continue
                end
                preyChannel = ['PreyCh' num2str(s)];
                [sizey,sizex] = size(app.totalCoApp.(preyChannel){1});
                selectedCoApp = zeros(sizey,sizex);
                selectedTotal = zeros(sizey,sizex);

                 % Get selected complex sizes 
                for d = app.MinSpinner.Value : app.MaxSpinner.Value 
                    if app.FilterKnownArtifactsCheckBox.Value
                        %Filtered Data
                        [sizey,sizex] = size(app.filteredCoApp.(preyChannel){d}); 
                        selectedCoApp(1:sizey,1:sizex) = selectedCoApp(1:sizey,1:sizex) + app.filteredCoApp.(preyChannel){d};
                        selectedTotal(1:sizey,1:sizex) = selectedTotal(1:sizey,1:sizex) + app.filteredCounted.(preyChannel){d};
                    else
                        %Unfiltered Data
                        [sizey,sizex] = size(app.totalCoApp.(preyChannel){d}); 
                        selectedCoApp(1:sizey,1:sizex) = selectedCoApp(1:sizey,1:sizex) + app.totalCoApp.(preyChannel){d};
                        selectedTotal(1:sizey,1:sizex) = selectedTotal(1:sizey,1:sizex) + app.totalCounted.(preyChannel){d};
                    end
                end
                
                % If there's a highlighted dataset, extract it
                if ~isempty(app.DatasetTable.Selection)
                    highlightedCoApp = selectedCoApp(app.DatasetTable.Selection,:);
                    highlightedTotal = selectedTotal(app.DatasetTable.Selection,:);
                    highlightedDensityIdx = densityIdx(app.DatasetTable.Selection,:);
                    highlightedCoApp(~highlightedDensityIdx(1:sizex)) = NaN;
                    highlightedTotal(~highlightedDensityIdx(1:sizex)) = NaN;
                    highlightedPctCoApp = 100 * (highlightedCoApp ./ highlightedTotal);
                end

                % Restrict data to only selected datasets
                datasetIdx = cell2mat(app.DatasetTable.Data(:,2));
                selectedCoApp = selectedCoApp(datasetIdx,:);
                selectedTotal = selectedTotal(datasetIdx,:);
                densityIdx = densityIdx(datasetIdx,:);
                [sizey,sizex] = size(selectedTotal);
                selectedCoApp(~densityIdx(1:sizey,1:sizex)) = NaN;
                selectedTotal(~densityIdx(1:sizey,1:sizex)) = NaN;

                % Pool data 
                pooledCoApp = sum(selectedCoApp,1,'omitnan');
                pooledTotal = sum(selectedTotal,1,'omitnan');
                
                % Calculate % co-appearance for each prey channel & cluster size with the bait
                app.pctCoApp{s} = 100 * (selectedCoApp ./ selectedTotal);
                app.pooledPctCoApp{s} = 100 * (pooledCoApp ./ pooledTotal);
                
                % Calculate moving (weighted) mean
                baitTrend = movsum(pooledTotal,app.trendWindow,'omitnan');
                preyTrend = movsum(pooledCoApp,app.trendWindow,'omitnan');
                colocTrend = 100 * (preyTrend ./ baitTrend);

                % Calculate total (time-independent) co-appearance
                % totalPctCoApp(d) = 100 * (sum(coAppearing) / sum(baitsCounted) );

                %Plot
                [~,lastWindow] = size(pooledCoApp);
                app.x = (2.5*[1:lastWindow]);
                if app.SamplesCheckBox.Value
                     app.dataPlots{end+1} = plot(app.UIAxes, app.x,app.pctCoApp{s},'o','MarkerSize',1.5,'Color',app.colors(s,:),'DisplayName',preyChannel);
                end
                if app.WeightedAverageCheckBox.Value
                     app.dataPlots{end+1} = plot(app.UIAxes, app.x,app.pooledPctCoApp{s},'.','MarkerSize',10,'Color',app.colors(s,:),'DisplayName',preyChannel);
                     app.dataPlots{end}.DataTipTemplate.DataTipRows(1).Label = 'Time(s)'; 
                     app.dataPlots{end}.DataTipTemplate.DataTipRows(2) = dataTipTextRow('CoAppearing:',pooledCoApp);
                     app.dataPlots{end}.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Total Baits:',pooledTotal);
                end
                if app.TrendlinesCheckBox.Value
                     app.dataPlots{end+1} = plot(app.UIAxes, app.x,colocTrend,'-','LineWidth',1,'Color',app.colors(s,:),'DisplayName',preyChannel);
                end
                if ~isempty(app.DatasetTable.Selection)
                     app.dataPlots{end+1} = plot(app.UIAxes, app.x,highlightedPctCoApp,'s','MarkerSize',5,'MarkerFaceColor',app.colors(s,:),'MarkerEdgeColor','black','DisplayName',preyChannel);
                     app.dataPlots{end}.DataTipTemplate.DataTipRows(1).Label = 'Time(s)'; 
                     app.dataPlots{end}.DataTipTemplate.DataTipRows(2) = dataTipTextRow('CoAppearing:',highlightedCoApp);
                     app.dataPlots{end}.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Total Baits:',highlightedTotal);
                end
                if app.FocusonAverageButton.Value && app.SamplesCheckBox.Value
                    index = ~cellfun(@isempty, app.pooledPctCoApp);
                    Ymax = max(app.pooledPctCoApp{index});
                    Ymax = 2 * ceil(Ymax);
                    set(app.UIAxes,'YLim',[0 min(Ymax,100)]);
                else
                    set(app.UIAxes,'YLimMode','auto');
                end
                app.EndTimeSpinner.Limits(2) = max(app.x);
                app.XmaxSpinner.Limits(2) = max(app.x);
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ask user to select data
            app = loadData(app);

            % Load and display data
            displayData(app);

            % Set initial X Axis limits
            set(app.UIAxes,'XLim',[app.XminSpinner.Value app.XmaxSpinner.Value]);
            set(app.ResidualAxes,'XLim',[app.XminSpinner.Value app.XmaxSpinner.Value]);
        end

        % Value changed function: ExcludeSparseDataCheckBox
        function ExcludeSparseDataCheckBoxValueChanged(app, event)
            % Clear fits, since they'll no longer be valid after changing data selection
            for a = 1:length(app.fitCurves)
                delete(app.fitCurves{a});
            end
            app.fitCurves = {};
            cla(app.ResidualAxes)
            
            % Update display
            displayData(app);
            
        end

        % Value changed function: ExcludeDenseDataCheckBox
        function ExcludeDenseDataCheckBoxValueChanged(app, event)
            % Clear fits, since they'll no longer be valid after changing data selection
            for a = 1:length(app.fitCurves)
                delete(app.fitCurves{a});
            end
            app.fitCurves = {};
            cla(app.ResidualAxes)
            
            % Update display
            displayData(app);
            
        end

        % Value changed function: MinSpinner
        function MinSpinnerValueChanged(app, event)
            if app.MinSpinner.Value > app.MaxSpinner.Value
                app.MaxSpinner.Value = app.MinSpinner.Value;
            end
            
            % Clear fits, since they'll no longer be valid after changing data selection
            for a = 1:length(app.fitCurves)
                delete(app.fitCurves{a});
            end
            app.fitCurves = {};
            cla(app.ResidualAxes)
            
            % Update display
            displayData(app);
            
        end

        % Value changed function: MaxSpinner
        function MaxSpinnerValueChanged(app, event)
            if app.MaxSpinner.Value < app.MinSpinner.Value
                app.MinSpinner.Value = app.MaxSpinner.Value;
            end
            
            % Clear fits, since they'll no longer be valid after changing data selection
            for a = 1:length(app.fitCurves)
                delete(app.fitCurves{a});
            end
            app.fitCurves = {};
            cla(app.ResidualAxes)
            
            % Update display
            displayData(app);
            
        end

        % Button pushed function: PerformFitButton
        function PerformFitButtonPushed(app, event)
            % Clear residuals from previous fit
            cla(app.ResidualAxes)
            % Set up fit
            opts = fitoptions('Method','NonlinearLeastSquares',...
               'Lower',[0,-inf,0],...
               'StartPoint',[50,0.01,1]);
            expdecay = fittype('a*exp(-b*x)+c',...
                               'independent','x',...
                               'coefficients',{'a','b','c'},...
                               'options',opts);
            
            % Fit selected data
             for s = 1:app.params.nChannels
                chIndex = find(app.params.preyChNums == s);
                if isempty(chIndex) || ~app.(['PreyCheckBox' num2str(chIndex)]).Value
                    continue
                end
                [~,startWindow] = min( abs( app.x - app.StartTimeSpinner.Value));
                [~,endWindow] = min( abs( app.x - app.EndTimeSpinner.Value));
                y_fit = app.pooledPctCoApp{s}(startWindow:endWindow);
                nanIdx = isnan(y_fit);
                y_fit = y_fit(~nanIdx);
                x_fit = app.x(startWindow:endWindow);
                x_fit = x_fit(~nanIdx);
                [f0, gof0] = fit(x_fit',y_fit',expdecay);
                
                % Plot fit curve on main axes
                tempfig = figure('Visible','off');
                axes(tempfig,'XLim',[app.StartTimeSpinner.Value app.EndTimeSpinner.Value]);
                fitPlot = plot(f0,'predobs');
                app.fitCurves{end+1} = copyobj(fitPlot, app.UIAxes);
                set(app.fitCurves{end},'Color',app.colors(s,:),'LineWidth',1)
                delete(tempfig);

                %Plot Residuals
                tempfig = figure('Visible','off');
                axes(tempfig,'XLim',[app.StartTimeSpinner.Value app.EndTimeSpinner.Value]);
                residPlot = plot(f0,x_fit',y_fit','residuals');
                app.residCurves = copyobj(residPlot, app.ResidualAxes);
                set(app.residCurves,'Color',app.colors(s,:),'MarkerSize',10,'LineWidth',1)
                delete(tempfig);                

                % Display Fit Results
                f0_CI = confint(f0);
                disp('')
                disp(['Results of fit for channel ' num2str(s)])
                disp(['   Peq (uncorrected) = ' num2str( f0.c ) '%' ])
                disp(['   koff = ' num2str( (1 - f0.c/100)*f0.b ) ' s^-1   (' num2str( (1 - f0.c/100)*f0_CI(1,2) ) ', ' num2str( (1 - f0.c/100)*f0_CI(2,2) ) ')'])
                disp(['   R^2 = ' num2str(gof0.adjrsquare)])
            end

        end

        % Button pushed function: ClearFitsButton
        function ClearFitsButtonPushed(app, event)
            for a = 1:length(app.fitCurves)
                delete(app.fitCurves{a});
            end
            app.fitCurves = {};
            cla(app.ResidualAxes)
        end

        % Button pushed function: LoadNewDataButton
        function LoadNewDataButtonPushed(app, event)
            % Clear existing data
            app.lowDensity = [];
            app.highDensity = [];
            app.totalCounted = struct();
            app.totalCoApp = struct();
            app.x = [];
            app.pctCoApp = {};
            app.pooledPctCoApp = {};

            % Clear existing plots
            cla(app.UIAxes)
            cla(app.ResidualAxes)
            
            % Ask user to select data
            app = loadData(app);

            % Load and display data
            displayData(app);
        end

        % Value changed function: PreyCheckBox1
        function PreyCheckBox1ValueChanged(app, event)
            displayData(app);
            
        end

        % Value changed function: PreyCheckBox2
        function PreyCheckBox2ValueChanged(app, event)
            displayData(app);
            
        end

        % Value changed function: PreyCheckBox3
        function PreyCheckBox3ValueChanged(app, event)
            displayData(app);
            
        end

        % Button pushed function: CopyPlottoFigureButton
        function CopyPlottoFigureButtonPushed(app, event)
            w = app.UIAxes.Position(3);
            h = app.UIAxes.Position(4);
            newFig = figure('Position',[100,100,w+100,h+100]);
            newAxes = copyobj(app.UIAxes,newFig);
            set(newAxes,'Position',[50 50 w h]);
        end

        % Button pushed function: ExportPlottoImageButton
        function ExportPlottoImageButtonPushed(app, event)
            tempfig = figure('Visible','off');
            copyobj(app.UIAxes,tempfig);
            [outfile,outpath] = uiputfile('plot.pdf','Save File Name');
            if verLessThan('matlab','9.8')
                export_fig([outpath filesep outfile],newfig1,'-pdf','-png','-transparent');
            else
                exportgraphics(app.generalUIAxes,[outpath filesep outfile],'ContentType','vector');
            end
            close(newfig1);
        end

        % Value changed function: XminSpinner
        function XminSpinnerValueChanged(app, event)
            set(app.UIAxes,'XLim',[app.XminSpinner.Value app.XmaxSpinner.Value]);
            set(app.ResidualAxes,'XLim',[app.XminSpinner.Value app.XmaxSpinner.Value]);
        end

        % Value changed function: XmaxSpinner
        function XmaxSpinnerValueChanged(app, event)
            set(app.UIAxes,'XLim',[app.XminSpinner.Value app.XmaxSpinner.Value]);
            set(app.ResidualAxes,'XLim',[app.XminSpinner.Value app.XmaxSpinner.Value]);
        end

        % Value changed function: TrendlinesCheckBox
        function TrendlinesCheckBoxValueChanged(app, event)
            displayData(app);
        end

        % Value changed function: WeightedAverageCheckBox
        function WeightedAverageCheckBoxValueChanged(app, event)
            displayData(app);
        end

        % Value changed function: SamplesCheckBox
        function SamplesCheckBoxValueChanged(app, event)
            displayData(app);
        end

        % Cell edit callback: DatasetTable
        function DatasetTableCellEdit(app, event)
            % Clear fits, since they'll no longer be valid after changing data selection
            for a = 1:length(app.fitCurves)
                delete(app.fitCurves{a});
            end
            app.fitCurves = {};
            cla(app.ResidualAxes)
            
            % Update display
            displayData(app);  
        end

        % Key press function: DatasetTable
        function DatasetTableKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'escape')
                app.DatasetTable.Selection = [];
                displayData(app);
            end
        end

        % Selection changed function: YAxisBehaviorButtonGroup
        function YAxisBehaviorButtonGroupSelectionChanged(app, event)
            displayData(app);            
        end

        % Callback function: DatasetTable
        function DatasetTableSelectionChanged(app, event)
            displayData(app);
        end

        % Value changed function: FilterKnownArtifactsCheckBox
        function FilterKnownArtifactsCheckBoxValueChanged(app, event)
            displayData(app);            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {523, 523};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {511, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExponentialDecayFitPanel = uipanel(app.LeftPanel);
            app.ExponentialDecayFitPanel.Title = 'Exponential Decay Fit';
            app.ClearFitsButton = uibutton(app.ExponentialDecayFitPanel, 'push');
            app.ClearFitsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFitsButtonPushed, true);
            app.ClearFitsButton.Text = 'Clear Fits';
            app.PerformFitButton = uibutton(app.ExponentialDecayFitPanel, 'push');
            app.PerformFitButton.ButtonPushedFcn = createCallbackFcn(app, @PerformFitButtonPushed, true);
            app.PerformFitButton.Text = 'Perform Fit';
            app.EndTimeSpinnerLabel = uilabel(app.ExponentialDecayFitPanel);
            app.EndTimeSpinnerLabel.Text = 'End Time';
            app.EndTimeSpinner = uispinner(app.ExponentialDecayFitPanel);
            app.EndTimeSpinner.Limits = [0 Inf];
            app.EndTimeSpinner.Value = Inf;
            app.StartTimeSpinnerLabel = uilabel(app.ExponentialDecayFitPanel);
            app.StartTimeSpinnerLabel.Text = 'Start Time';
            app.StartTimeSpinner = uispinner(app.ExponentialDecayFitPanel);
            app.StartTimeSpinner.Limits = [0 Inf];
            app.DataFilteringPanel = uipanel(app.LeftPanel);
            app.DataFilteringPanel.Title = 'Data Filtering';
            app.MinLabel = uilabel(app.DataFilteringPanel);
            app.MinLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.DataFilteringPanel);
            app.MinSpinner.Limits = [1 Inf];
            app.MinSpinner.RoundFractionalValues = 'on';
            app.MinSpinner.ValueChangedFcn = createCallbackFcn(app, @MinSpinnerValueChanged, true);
            app.MinSpinner.Value = 1;
            app.MaxSpinnerLabel = uilabel(app.DataFilteringPanel);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.DataFilteringPanel);
            app.MaxSpinner.Limits = [1 2];
            app.MaxSpinner.RoundFractionalValues = 'on';
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxSpinnerValueChanged, true);
            app.MaxSpinner.Value = 1;
            app.BaitPhotobleachingStepsLabel = uilabel(app.DataFilteringPanel);
            app.BaitPhotobleachingStepsLabel.Text = 'Bait Photobleaching Steps';
            app.ExcludeSparseDataCheckBox = uicheckbox(app.DataFilteringPanel);
            app.ExcludeSparseDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeSparseDataCheckBoxValueChanged, true);
            app.ExcludeSparseDataCheckBox.Text = 'Exclude Sparse Data';
            app.ExcludeDenseDataCheckBox = uicheckbox(app.DataFilteringPanel);
            app.ExcludeDenseDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeDenseDataCheckBoxValueChanged, true);
            app.ExcludeDenseDataCheckBox.Text = 'Exclude Dense Data';
            app.FilterKnownArtifactsCheckBox = uicheckbox(app.DataFilteringPanel);
            app.FilterKnownArtifactsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterKnownArtifactsCheckBoxValueChanged, true);
            app.FilterKnownArtifactsCheckBox.Text = 'Filter Known Artifacts';
            app.FilterKnownArtifactsCheckBox.Value = true;
            app.DataSelectionPanel = uipanel(app.LeftPanel);
            app.DataSelectionPanel.Title = 'Data Selection';
            app.LoadNewDataButton = uibutton(app.DataSelectionPanel, 'push');
            app.LoadNewDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewDataButtonPushed, true);
            app.LoadNewDataButton.Text = 'Load New Data';
            app.SamplesCheckBox = uicheckbox(app.DataSelectionPanel);
            app.SamplesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SamplesCheckBoxValueChanged, true);
            app.SamplesCheckBox.Text = 'Show Individual Samples';
            app.SamplesCheckBox.Value = true;
            app.WeightedAverageCheckBox = uicheckbox(app.DataSelectionPanel);
            app.WeightedAverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @WeightedAverageCheckBoxValueChanged, true);
            app.WeightedAverageCheckBox.Text = 'Show Weighted Average';
            app.WeightedAverageCheckBox.Value = true;
            app.TrendlinesCheckBox = uicheckbox(app.DataSelectionPanel);
            app.TrendlinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrendlinesCheckBoxValueChanged, true);
            app.TrendlinesCheckBox.Text = 'Show Trendlines';
            app.TrendlinesCheckBox.Value = true;
            app.DatasetTable = uitable(app.DataSelectionPanel);
            app.DatasetTable.ColumnName = '';
            app.DatasetTable.RowName = {};
            app.DatasetTable.SelectionType = 'row';
            app.DatasetTable.CellEditCallback = createCallbackFcn(app, @DatasetTableCellEdit, true);
            app.DatasetTable.SelectionChangedFcn = createCallbackFcn(app, @DatasetTableSelectionChanged, true);
            app.DatasetTable.KeyPressFcn = createCallbackFcn(app, @DatasetTableKeyPress, true);
            app.PreyChannelSelectionPanel = uipanel(app.LeftPanel);
            app.PreyChannelSelectionPanel.Title = 'Prey Channel Selection';
            app.PreyCheckBox1 = uicheckbox(app.PreyChannelSelectionPanel);
            app.PreyCheckBox1.ValueChangedFcn = createCallbackFcn(app, @PreyCheckBox1ValueChanged, true);
            app.PreyCheckBox1.Text = 'Prey 1';
            app.PreyCheckBox1.Value = true;
            app.PreyCheckBox2 = uicheckbox(app.PreyChannelSelectionPanel);
            app.PreyCheckBox2.ValueChangedFcn = createCallbackFcn(app, @PreyCheckBox2ValueChanged, true);
            app.PreyCheckBox2.Text = 'Prey 2';
            app.PreyCheckBox2.Value = true;
            app.PreyCheckBox3 = uicheckbox(app.PreyChannelSelectionPanel);
            app.PreyCheckBox3.ValueChangedFcn = createCallbackFcn(app, @PreyCheckBox3ValueChanged, true);
            app.PreyCheckBox3.Text = 'Prey 3';
            app.PreyCheckBox3.Value = true;
            app.YAxisBehaviorButtonGroup = uibuttongroup(app.LeftPanel);
            app.YAxisBehaviorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YAxisBehaviorButtonGroupSelectionChanged, true);
            app.YAxisBehaviorButtonGroup.Title = 'Y Axis Behavior';
            app.ShowAllDataButton = uiradiobutton(app.YAxisBehaviorButtonGroup);
            app.ShowAllDataButton.Text = 'Show All Data';
            app.ShowAllDataButton.Value = true;
            app.FocusonAverageButton = uiradiobutton(app.YAxisBehaviorButtonGroup);
            app.FocusonAverageButton.Text = 'Focus on Average';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Time after lysis (s)')
            ylabel(app.UIAxes, '% Co-appearance')
            zlabel(app.UIAxes, 'Z')
            app.ResidualAxes = uiaxes(app.RightPanel);
            ylabel(app.ResidualAxes, 'Fit Residuals')
            zlabel(app.ResidualAxes, 'Z')
            app.ResidualAxes.XAxisLocation = 'top';
            app.ResidualAxes.XTickLabel = '';
            app.ExportPlottoImageButton = uibutton(app.RightPanel, 'push');
            app.ExportPlottoImageButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlottoImageButtonPushed, true);
            app.ExportPlottoImageButton.Text = 'Export Plot to Image';
            app.CopyPlottoFigureButton = uibutton(app.RightPanel, 'push');
            app.CopyPlottoFigureButton.ButtonPushedFcn = createCallbackFcn(app, @CopyPlottoFigureButtonPushed, true);
            app.CopyPlottoFigureButton.Text = 'Copy Plot to Figure';
            app.XminSpinner = uispinner(app.RightPanel);
            app.XminSpinner.Limits = [0 Inf];
            app.XminSpinner.ValueChangedFcn = createCallbackFcn(app, @XminSpinnerValueChanged, true);
            app.XLimitsSpinnerLabel = uilabel(app.RightPanel);
            app.XLimitsSpinnerLabel.Text = 'X Limits';
            app.XmaxSpinner = uispinner(app.RightPanel);
            app.XmaxSpinner.Limits = [0 Inf];
            app.XmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @XmaxSpinnerValueChanged, true);
            app.XmaxSpinner.Value = Inf;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoApp_vs_Time_plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end