classdef colocalization_inspector_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figureWindow               matlab.ui.Figure
        greenImagePanel            matlab.ui.control.UIAxes
        redImagePanel              matlab.ui.control.UIAxes
        mergedImagePanel           matlab.ui.control.UIAxes
        positionText               matlab.ui.control.Label
        instructionText            matlab.ui.control.Label
        colocText                  matlab.ui.control.Label
        buttonPanel                matlab.ui.container.Panel
        greenCheckbox              matlab.ui.control.CheckBox
        redCheckbox                matlab.ui.control.CheckBox
        colocCheckbox              matlab.ui.control.CheckBox
        redText                    matlab.ui.control.Label
        greenText                  matlab.ui.control.Label
        loadButton                 matlab.ui.control.Button
        artifactPanel              matlab.ui.container.Panel
        drawROIbutton              matlab.ui.control.Button
        excludeROIbutton           matlab.ui.control.Button
        defineROIbutton            matlab.ui.control.Button
        reRegisterButton           matlab.ui.control.Button
        tossImageButton            matlab.ui.control.Button
        uibuttongroup1             matlab.ui.container.ButtonGroup
        text12                     matlab.ui.control.Label
        roiThisImageButton         matlab.ui.control.RadioButton
        roiAllImagesButton         matlab.ui.control.RadioButton
        busyTextPanel              matlab.ui.container.Panel
        busyText                   matlab.ui.control.Label
        LeftChannelButtonGroup     matlab.ui.container.ButtonGroup
        LeftBlueChannelButton      matlab.ui.control.RadioButton
        LeftGreenChannelButton     matlab.ui.control.RadioButton
        LeftRedChannelButton       matlab.ui.control.RadioButton
        LeftFarRedChannelButton    matlab.ui.control.RadioButton
        CenterChannelButtonGroup   matlab.ui.container.ButtonGroup
        CenterBlueChannelButton    matlab.ui.control.RadioButton
        CenterGreenChannelButton   matlab.ui.control.RadioButton
        CenterRedChannelButton     matlab.ui.control.RadioButton
        CenterFarRedChannelButton  matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        circle = uint16([    0,      0,      0,  65500,  65500,  65500,  65500,  65500,      0,      0,      0;
                             0,      0,  65500,      0,      0,      0,      0,      0,  65500,      0,      0;
                             0,  65500,      0,      0,      0,      0,      0,      0,      0,  65500,      0;
                         65500,      0,      0,      0,      0,      0,      0,      0,      0,      0,  65500;
                         65500,      0,      0,      0,      0,      0,      0,      0,      0,      0,  65500;
                         65500,      0,      0,      0,      0,      0,      0,      0,      0,      0,  65500;
                         65500,      0,      0,      0,      0,      0,      0,      0,      0,      0,  65500;
                         65500,      0,      0,      0,      0,      0,      0,      0,      0,      0,  65500;
                             0,  65500,      0,      0,      0,      0,      0,      0,      0,  65500,      0;
                             0,      0,  65500,      0,      0,      0,      0,      0,  65500,      0,      0;
                             0,      0,      0,  65500,  65500,  65500,  65500,  65500,      0,      0,      0]); %Circle drawn on images
         
         arrayPos % Where we are in the image sequence
         gridData % Main data structure
         statsByColor % Summary statistics for data
         imgPath % Location of images
         matPath % Location of data
         matFile % Holds the data
         
         % Properties of data
         channels
         nChannels
         nElements
         params
         
         % Display properties
         screenSize
         scale
        
         ROI % ROI to be excluded or kept in data
    end
    
    methods (Access = private)
        function deletingROI(app)
            set(app.defineROIbutton,'Enable','off');
            set(app.excludeROIbutton,'Enable','off');
        end
        
        function displayImages(app)
            set(app.busyTextPanel,'Visible','on');
            drawnow;
            b=app.arrayPos;
            
            %Figure out which channels are selected
            leftChannel = app.LeftChannelButtonGroup.SelectedObject.Tag;
            centerChannel = app.CenterChannelButtonGroup.SelectedObject.Tag;
            
            %Get images
            greenImageName = [app.gridData(b).imageName '_' leftChannel 'avg.tif'];
            redImageName = [app.gridData(b).imageName '_' centerChannel 'avg.tif'];

            greenCh = imread([app.imgPath filesep greenImageName]);
            [ymax, xmax] = size(greenCh);
            greenCh = imadjust(greenCh,stretchlim(greenCh,0.0005),[]);
            if isfield(app.statsByColor, [leftChannel 'RegistrationData']) %Apply registration correct if applicable
                greenCh = imwarp(greenCh,app.statsByColor.([leftChannel 'RegistrationData']).SpatialRefObj, app.statsByColor.([leftChannel 'RegistrationData']).Transformation,'OutputView', imref2d(size(greenCh)));
            end
            
            redCh = imread([app.imgPath filesep redImageName]);
            redCh = imadjust(redCh,stretchlim(redCh,0.0005),[]);
            if isfield(app.statsByColor, [centerChannel 'RegistrationData']) %Apply registration correct if applicable
                redCh = imwarp(redCh,app.statsByColor.([centerChannel 'RegistrationData']).SpatialRefObj, app.statsByColor.([centerChannel 'RegistrationData']).Transformation,'OutputView', imref2d(size(redCh)));
            end
            
            %Create images for display
            greenImage = uint16(zeros(ymax,xmax,3));
            greenImage(:,:,1) = greenCh;
            greenImage(:,:,2) = greenCh;
            greenImage(:,:,3) = greenCh;
            
            redImage = uint16(zeros(ymax,xmax,3));
            redImage(:,:,1) = redCh;
            redImage(:,:,2) = redCh;
            redImage(:,:,3) = redCh;
            
            mergedImage = uint16(zeros(ymax,xmax,3));
            mergedImage(:,:,1) = redCh;
            mergedImage(:,:,2) = greenCh;
            
            %Draw circles around spots
            greenOn = get(app.greenCheckbox,'Value');
            redOn = get(app.redCheckbox,'Value');
            colocOn = get(app.colocCheckbox,'Value');
            if strcmp(app.LeftChannelButtonGroup.SelectedObject.Tag, app.CenterChannelButtonGroup.SelectedObject.Tag)
                colocOn = false; %Don't try to circle colocalized spots if both channels are the same
            end
            
            if app.gridData(b).([leftChannel 'SpotCount']) > 0 && (greenOn || colocOn)
                for a = 1:app.gridData(b).([leftChannel 'SpotCount'])
                    if isfield(app.statsByColor, [leftChannel 'RegistrationData']) %Apply registration correction if applicable
                        [xcoord,ycoord] = transformPointsForward(app.statsByColor.([leftChannel 'RegistrationData']).Transformation, app.gridData(b).([leftChannel 'SpotData'])(a).spotLocation(1), app.gridData(b).([leftChannel 'SpotData'])(a).spotLocation(2));
                        xcoord = round(xcoord);
                        ycoord = round(ycoord);
                        if (xcoord <= 5 || xcoord+5 > xmax || ycoord <= 5 || ycoord+5 > ymax)
                            continue
                        end
                    else
                        xcoord = app.gridData(b).([leftChannel 'SpotData'])(a).spotLocation(1);
                        ycoord = app.gridData(b).([leftChannel 'SpotData'])(a).spotLocation(2);
                    end
            
                    %Green Circles for Spots in Green Channel
                    if greenOn || (colocOn && isnumeric( app.gridData(b).([leftChannel centerChannel 'ColocSpots']) ) && app.gridData(b).([leftChannel 'SpotData'])(a).(['coloc' centerChannel]))
                        greenImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2) = max(app.circle, greenImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2));
                        redImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2) = max(app.circle, redImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2));
                        mergedImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2) = max(app.circle, mergedImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2));
                    end
                    %Yellow Circles for Colocalized Spots
                    if colocOn && isnumeric( app.gridData(b).([leftChannel centerChannel 'ColocSpots']) ) && app.gridData(b).([leftChannel 'SpotData'])(a).(['coloc' centerChannel])
                        greenImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1) = max(app.circle, greenImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1));
                        redImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1) = max(app.circle, redImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1));
                        mergedImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1) = max(app.circle, mergedImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1));
                    end
                end
            end
            
            if app.gridData(b).([centerChannel 'SpotCount']) > 0 && (redOn || colocOn)
                for a = 1:app.gridData(b).([centerChannel 'SpotCount'])
                    if isfield(app.statsByColor, [centerChannel 'RegistrationData']) %Apply registration correct if applicable
                        [xcoord,ycoord] = transformPointsForward(app.statsByColor.([centerChannel 'RegistrationData']).Transformation, app.gridData(b).([centerChannel 'SpotData'])(a).spotLocation(1), app.gridData(b).([centerChannel 'SpotData'])(a).spotLocation(2));
                        xcoord = round(xcoord);
                        ycoord = round(ycoord);
                    else
                        xcoord = app.gridData(b).([centerChannel 'SpotData'])(a).spotLocation(1);
                        ycoord = app.gridData(b).([centerChannel 'SpotData'])(a).spotLocation(2);
                    end
                    %Skip this circle if it would fall outside the image after registration
                    if (xcoord <= 5 || xcoord+5 >= xmax || ycoord <= 5 || ycoord+5 >= ymax)
                        continue
                    end
                    %Red Circles for Spots in Red Channel
                    if redOn || (colocOn && isnumeric( app.gridData(b).([centerChannel leftChannel 'ColocSpots']) ) && app.gridData(b).([centerChannel 'SpotData'])(a).(['coloc' leftChannel]))
                        greenImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1) = max(app.circle, greenImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1));
                        redImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1) = max(app.circle, redImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1));
                        mergedImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1) = max(app.circle, mergedImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,1));
                    end
                    %Yellow Circles for Colocalized Spots
                    if colocOn && isnumeric( app.gridData(b).([centerChannel leftChannel 'ColocSpots']) ) && app.gridData(b).([centerChannel 'SpotData'])(a).(['coloc' leftChannel])
                        greenImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2) = max(app.circle, greenImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2));
                        redImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2) = max(app.circle, redImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2));
                        mergedImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2) = max(app.circle, mergedImage(ycoord-5:ycoord+5,xcoord-5:xcoord+5,2));
                    end
                end
            end
            
            %Show images
            I = imshow(greenImage,'Parent',app.greenImagePanel,'XData', [1 app.greenImagePanel.Position(3)],'YData', [1 app.greenImagePanel.Position(4)]);
            app.greenImagePanel.XAxis.TickLabels = {};
            app.greenImagePanel.YAxis.TickLabels = {};
            set(app.greenImagePanel, 'XLim', [0 I.XData(2)], 'YLim', [0 I.YData(2)]);
            
            I = imshow(redImage,'Parent',app.redImagePanel,'XData', [1 app.redImagePanel.Position(3)],'YData', [1 app.redImagePanel.Position(4)]);
            app.redImagePanel.XAxis.TickLabels = {};
            app.redImagePanel.YAxis.TickLabels = {};
            set(app.redImagePanel, 'XLim', [0 I.XData(2)], 'YLim', [0 I.YData(2)]);
            
            I = imshow(mergedImage,'Parent',app.mergedImagePanel,'XData', [1 app.mergedImagePanel.Position(3)],'YData', [1 app.mergedImagePanel.Position(4)]);
            app.mergedImagePanel.XAxis.TickLabels = {};
            app.mergedImagePanel.YAxis.TickLabels = {};
            set(app.mergedImagePanel, 'XLim', [0 I.XData(2)], 'YLim', [0 I.YData(2)]);
            
            set(app.greenText,'Text',[num2str(app.gridData(b).([leftChannel 'SpotCount'])) ' ' leftChannel ' spots']);
            set(app.redText,'Text',[num2str(app.gridData(b).([centerChannel 'SpotCount'])) ' ' centerChannel ' spots'])
            if strcmp(leftChannel, centerChannel)
                set(app.colocText,'Visible','off');
            else
                set(app.colocText,'Visible','on');
                if isnumeric( app.gridData(b).([centerChannel leftChannel 'ColocSpots']) )
                    set(app.colocText,'Text',[num2str(app.gridData(b).([leftChannel centerChannel 'ColocSpots'])) ' colocalized spots']);
                else
                    set(app.colocText,'Text',app.gridData(b).([centerChannel leftChannel 'ColocSpots']));
                end
            end
            
            %Update visibility of components
            set(app.positionText,'Text',['Stage Position: ' app.gridData(b).imageName]);
            set(app.busyTextPanel,'Visible','off');
            set(app.excludeROIbutton,'Enable','off');
            set(app.defineROIbutton,'Enable','off');
        end
        
        function app = initializeData(app)
            b=1;
            app.arrayPos = b;
            
            %Get the data from the spot counter
            if ~isempty(app.matPath)
                startPath = app.matPath;
            else
                startPath = pwd;
            end
            [app.matFile, app.matPath] = uigetfile('*.mat','Choose a .mat file with data from the spot counter',startPath);
            load([app.matPath filesep app.matFile]);

            %locate image folder
            nameIdx = strfind(app.matFile,'_filtered.mat');
            if ~isempty(nameIdx)
                app.imgPath = [app.matPath app.matFile(1:nameIdx-1)];
            else
                app.imgPath = [app.matPath filesep app.matFile(1:end-4)];
            end
            
            app.gridData = gridData; clear gridData;
            app.channels = channels; clear channels;
            app.nChannels = nChannels; clear nChannels;
            app.params = params; clear params
            app.statsByColor = statsByColor; clear statsByColor;
            gridSize = size(app.gridData);
            app.nElements = gridSize(1)*gridSize(2);
            
            %Get first image
            firstImageName = [app.gridData(b).imageName '_' app.channels{1} 'avg.tif'];
            firstImage = imread([app.imgPath filesep firstImageName]);
            [ymax, xmax] = size(firstImage);
            
            %Set up figure window
            app.screenSize = get(0,'ScreenSize');
            figureWidth = app.screenSize(3);
            imageWidth = (figureWidth-120) / 3;
            xscale = imageWidth / xmax;
            figureHeight = app.screenSize(4);
            imageHeight = figureHeight - 350;
            yscale = imageHeight / ymax;
            app.scale = min([xscale yscale]);
            imageWidth = floor(app.scale * xmax);
            figureWidth = imageWidth*3 + 120;
            imageHeight = floor(app.scale * ymax);
            figureHeight = imageHeight + 350;
            set(app.figureWindow,'Name',app.matFile(1:end-4), 'Position',[0 app.screenSize(4)-figureHeight-100 figureWidth figureHeight]);
            set(app.instructionText,'Position',[(figureWidth-250)/2 15 250 60]);
            set(app.greenImagePanel,'Position',[30 80 imageWidth imageHeight],'XLim',[0 imageWidth],'YLim',[0 imageHeight]);
            set(app.redImagePanel,'Position',[imageWidth+60 80 imageWidth imageHeight],'XLim',[0 imageWidth],'YLim',[0 imageHeight]);
            set(app.mergedImagePanel,'Position',[imageWidth*2+90 80 imageWidth imageHeight],'XLim',[0 imageWidth],'YLim',[0 imageHeight]);
            
            set(app.LeftChannelButtonGroup,'Position',[30+(imageWidth-200)/2 imageHeight+120 200 app.nChannels*30]);
            set(app.CenterChannelButtonGroup,'Position',[imageWidth+60+(imageWidth-200)/2 imageHeight+120 200 app.nChannels*30]);
            for a = 1:app.nChannels
                color = app.channels{a};
                set(app.(['Left' color 'ChannelButton']), 'Position',[5 5+(app.nChannels-a)*30 190 30],'Visible','on');
                set(app.(['Center' color 'ChannelButton']), 'Position',[5 5+(app.nChannels-a)*30 190 30],'Visible','on');
            end
            set(app.(['Left' app.channels{1} 'ChannelButton']), 'Value',1);
            set(app.greenCheckbox, 'Text', [app.channels{1} ' Spots']);
            if app.nChannels>1
                set(app.(['Center' app.channels{2} 'ChannelButton']), 'Value',1);
                set(app.redCheckbox, 'Text', [app.channels{2} ' Spots']);
            else
                set(app.(['Center' app.channels{1} 'ChannelButton']), 'Value',1);
                set(app.redCheckbox, 'Value', 0, 'Enable', 'off');
                set(app.colocCheckbox, 'Value', 0, 'Enable', 'off');
            end
            
            set(app.greenText,'Position',[30+(imageWidth-200)/2 imageHeight+90 200 20]);
            set(app.redText,'Position',[imageWidth+60+(imageWidth-200)/2 imageHeight+90 200 20]);
            set(app.colocText,'Position',[2*imageWidth+90+(imageWidth-200)/2 imageHeight+90 200 20]);
            set(app.positionText,'Position',[(figureWidth-500)/2 imageHeight+250 500 20]);
            set(app.buttonPanel,'Position',[30 imageHeight+200 200 100]);
            set(app.artifactPanel, 'Position',[2*imageWidth+90+(imageWidth-520)/2 imageHeight+150 520 100]);
            set(app.loadButton, 'Position',[400 imageHeight+250 150 30]);
            set(app.busyTextPanel,'Position',[(figureWidth-150)/2 imageHeight/2 150 80], 'Visible','off');
            
            app.ROI = [];
        end
        
        function movedROI(app, ~, evt)
            app.ROI = evt.CurrentPosition;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function colocalization_inspector_GUI_OpeningFcn(app, varargin)
                                   
            %Load data from the spot counter and set up figure window
            app = initializeData(app);
            
            %Display Images
            displayImages(app);

        end

        % Value changed function: colocCheckbox
        function colocCheckbox_Callback(app, event)
            displayImages(app);
        end

        % Button pushed function: defineROIbutton
        function defineROIbutton_Callback(app, event)
            set(app.busyTextPanel,'Visible','on');
            drawnow;
            if get(app.roiAllImagesButton,'Value')
                % If "all images" is selected, do all
                wb = waitbar(0,'');
                for c=1:app.nElements
                    waitbar((c-1)/app.nElements, wb, ['Processing image ' num2str(c) ' of ' num2str(app.nElements)]);
                    [app.gridData, app.statsByColor, ~] = defineROI(app.gridData, app.channels, app.nChannels, app.nElements, app.params, app.statsByColor, app.matPath, app.matFile, c, app.ROI, 1);
                end
                set(app.roiAllImagesButton,'Value',0);
                set(app.roiThisImageButton,'Value',1);
                close(wb);
            else
                % Otherwise, just do the current image
                [app.gridData, app.statsByColor, ~] = defineROI(app.gridData, app.channels, app.nChannels, app.nElements, app.params, app.statsByColor, app.matPath, app.matFile, app.arrayPos, app.ROI, 1);
            end
            
            displayImages(app);
        end

        % Button pushed function: drawROIbutton
        function drawROIbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            rect = drawrectangle(app.mergedImagePanel,'Color','cyan','FaceAlpha',0);
            app.ROI = rect.Position;
            set(app.defineROIbutton,'Enable','on');
            set(app.excludeROIbutton,'Enable','on');
            addlistener(rect, 'DeletingROI', @(src,evt) deletingROI(app));
            addlistener(rect, 'ROIMoved', @(src,evt) movedROI(app, src,evt));
        end

        % Button pushed function: excludeROIbutton
        function excludeROIbutton_Callback(app, event)
            set(app.busyTextPanel,'Visible','on');
            drawnow;
            if get(app.roiAllImagesButton,'Value')
                % If "all images" is selected, do all
                wb = waitbar(0,'');
                for c=1:app.nElements
                    waitbar((c-1)/app.nElements, wb, ['Processing image ' num2str(c) ' of ' num2str(app.nElements)]);
                    [app.gridData, app.statsByColor, ~] = defineROI(app.gridData, app.channels, app.nChannels, app.nElements, app.params, app.statsByColor, app.matPath, app.matFile, c, app.ROI, 0);
                end
                set(app.roiAllImagesButton,'Value',0);
                set(app.roiThisImageButton,'Value',1);
                close(wb);
            else
                % Otherwise, just do the current image
                [app.gridData, app.statsByColor, ~] = defineROI(app.gridData, app.channels, app.nChannels, app.nElements, app.params, app.statsByColor, app.matPath, app.matFile, app.arrayPos, app.ROI, 0);
            end
            displayImages(app);
        end

        % Window key press function: figureWindow
        function figureWindow_WindowKeyPressFcn(app, event)
            
            %Decide what to do
            b = app.arrayPos;
            switch event.Key 
                case 'n'
                    b = b+1;
                case 'p'
                    b = b-1;
                case 'r'
                    b=round(rand(1)*app.nElements);
                case {'backspace', 'delete'}
                    set(app.defineROIbutton,'Enable','off');
                    set(app.excludeROIbutton,'Enable','off');
                otherwise   
                    return
            end
            if b<1
                b=app.nElements;
            end
            if b>app.nElements
                b=1;
            end
            app.arrayPos = b;
            
            %Update Image Display
            displayImages(app);
        end

        % Value changed function: greenCheckbox
        function greenCheckbox_Callback(app, event)
            displayImages(app);
        end

        % Button pushed function: loadButton
        function loadButton_Callback(app, event)
            app = initializeData(app);

            displayImages(app);
        end

        % Button pushed function: reRegisterButton
        function reRegisterButton_Callback(app, event)
            set(app.busyTextPanel,'Visible','on');
            drawnow;
            [app.gridData, app.statsByColor, ~] = reRegister(app.gridData, app.channels, app.nChannels, app.params, app.statsByColor, app.matPath, app.matFile, app.arrayPos);
            
            displayImages(app);
            set(app.busyTextPanel,'Visible','off');
        end

        % Value changed function: redCheckbox
        function redCheckbox_Callback(app, event)
            displayImages(app);
        end

        % Button pushed function: tossImageButton
        function tossImageButton_Callback(app, event)
            set(app.busyTextPanel,'Visible','on');
            drawnow;
            [gridData, ~] = tossImage(app.matPath, app.matFile, app.arrayPos);
            app.gridData = gridData; clear gridData;
            gridSize = size(app.gridData);
            app.nElements = gridSize(1)*gridSize(2);
            if app.arrayPos > app.nElements
                app.arrayPos = 1;
            end
            displayImages(app);
        end

        % Selection changed function: LeftChannelButtonGroup
        function LeftChannelButtonGroupSelectionChanged(app, event)
            displayImages(app);
        end

        % Selection changed function: CenterChannelButtonGroup
        function CenterChannelButtonGroupSelectionChanged(app, event)
            displayImages(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figureWindow = uifigure('Visible', 'off');
            app.figureWindow.Name = 'spotcount_inspector_GUI';
            app.figureWindow.WindowKeyPressFcn = createCallbackFcn(app, @figureWindow_WindowKeyPressFcn, true);
            app.figureWindow.Tag = 'figureWindow';
            app.greenImagePanel = uiaxes(app.figureWindow);
            app.greenImagePanel.XTick = [];
            app.greenImagePanel.YTick = [];
            app.greenImagePanel.Tag = 'greenImagePanel';
            app.redImagePanel = uiaxes(app.figureWindow);
            app.redImagePanel.XLim = [0 1];
            app.redImagePanel.YLim = [0 1];
            app.redImagePanel.XTick = [];
            app.redImagePanel.XTickLabel = {};
            app.redImagePanel.YTick = [];
            app.redImagePanel.YTickLabel = {};
            app.redImagePanel.Tag = 'redImagePanel';
            app.mergedImagePanel = uiaxes(app.figureWindow);
            app.mergedImagePanel.XTick = [];
            app.mergedImagePanel.YTick = [];
            app.mergedImagePanel.Tag = 'mergedImagePanel';
            app.positionText = uilabel(app.figureWindow);
            app.positionText.Tag = 'positionText';
            app.positionText.Text = 'Static Text';
            app.instructionText = uilabel(app.figureWindow);
            app.instructionText.Tag = 'instructionText';
            app.instructionText.Text = {'N to move to next image'; 'P to move to previous image'; 'R to move to a random image'; ''};
            app.colocText = uilabel(app.figureWindow);
            app.colocText.Tag = 'colocText';
            app.colocText.Text = 'Static Text';
            app.buttonPanel = uipanel(app.figureWindow);
            app.buttonPanel.Title = 'Show:';
            app.buttonPanel.Tag = 'buttonPanel';
            app.greenCheckbox = uicheckbox(app.buttonPanel);
            app.greenCheckbox.ValueChangedFcn = createCallbackFcn(app, @greenCheckbox_Callback, true);
            app.greenCheckbox.Tag = 'greenCheckbox';
            app.greenCheckbox.Text = 'Green Spots';
            app.greenCheckbox.Value = true;
            app.redCheckbox = uicheckbox(app.buttonPanel);
            app.redCheckbox.ValueChangedFcn = createCallbackFcn(app, @redCheckbox_Callback, true);
            app.redCheckbox.Tag = 'redCheckbox';
            app.redCheckbox.Text = 'Red Spots';
            app.redCheckbox.Value = true;
            app.colocCheckbox = uicheckbox(app.buttonPanel);
            app.colocCheckbox.ValueChangedFcn = createCallbackFcn(app, @colocCheckbox_Callback, true);
            app.colocCheckbox.Tag = 'colocCheckbox';
            app.colocCheckbox.Text = 'Colocalized Spots';
            app.colocCheckbox.Value = true;
            app.redText = uilabel(app.figureWindow);
            app.redText.Tag = 'redText';
            app.redText.Text = 'Static Text';
            app.greenText = uilabel(app.figureWindow);
            app.greenText.Tag = 'greenText';
            app.greenText.Text = 'Static Text';
            app.loadButton = uibutton(app.figureWindow, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButton_Callback, true);
            app.loadButton.Tag = 'loadButton';
            app.loadButton.Text = 'Load New Dataset';
            app.artifactPanel = uipanel(app.figureWindow);
            app.artifactPanel.Title = 'Artifact Removal';
            app.artifactPanel.Tag = 'artifactPanel';
            app.drawROIbutton = uibutton(app.artifactPanel, 'push');
            app.drawROIbutton.ButtonPushedFcn = createCallbackFcn(app, @drawROIbutton_Callback, true);
            app.drawROIbutton.Tag = 'drawROIbutton';
            app.drawROIbutton.Tooltip = 'Click to select a region of the merged image';
            app.drawROIbutton.Text = 'Draw Region';
            app.excludeROIbutton = uibutton(app.artifactPanel, 'push');
            app.excludeROIbutton.ButtonPushedFcn = createCallbackFcn(app, @excludeROIbutton_Callback, true);
            app.excludeROIbutton.Tag = 'excludeROIbutton';
            app.excludeROIbutton.Enable = 'off';
            app.excludeROIbutton.Tooltip = 'Click to exclude the selected region from the analysis';
            app.excludeROIbutton.Text = 'Exclude Region';
            app.defineROIbutton = uibutton(app.artifactPanel, 'push');
            app.defineROIbutton.ButtonPushedFcn = createCallbackFcn(app, @defineROIbutton_Callback, true);
            app.defineROIbutton.Tag = 'defineROIbutton';
            app.defineROIbutton.Enable = 'off';
            app.defineROIbutton.Tooltip = 'Click to keep the selected region and exclude the rest of this image from the analysis';
            app.defineROIbutton.Text = 'Define ROI';
            app.reRegisterButton = uibutton(app.artifactPanel, 'push');
            app.reRegisterButton.ButtonPushedFcn = createCallbackFcn(app, @reRegisterButton_Callback, true);
            app.reRegisterButton.Tag = 'reRegisterButton';
            app.reRegisterButton.Tooltip = 'Click to re-register this sample using the currently-displayed image';
            app.reRegisterButton.Text = 'Redo Registration';
            app.tossImageButton = uibutton(app.artifactPanel, 'push');
            app.tossImageButton.ButtonPushedFcn = createCallbackFcn(app, @tossImageButton_Callback, true);
            app.tossImageButton.Tag = 'tossImageButton';
            app.tossImageButton.Text = 'Toss Image';
            app.uibuttongroup1 = uibuttongroup(app.artifactPanel);
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.text12 = uilabel(app.uibuttongroup1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Apply region to:';
            app.roiThisImageButton = uiradiobutton(app.uibuttongroup1);
            app.roiThisImageButton.Tag = 'roiThisImageButton';
            app.roiThisImageButton.Text = 'This image';
            app.roiThisImageButton.Value = true;
            app.roiAllImagesButton = uiradiobutton(app.uibuttongroup1);
            app.roiAllImagesButton.Tag = 'roiAllImagesButton';
            app.roiAllImagesButton.Text = 'All images';
            app.busyTextPanel = uipanel(app.figureWindow);
            app.busyTextPanel.Tag = 'busyTextPanel';
            app.busyText = uilabel(app.busyTextPanel);
            app.busyText.Tag = 'busyText';
            app.busyText.Text = 'Working...';
            app.LeftChannelButtonGroup = uibuttongroup(app.figureWindow);
            app.LeftChannelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LeftChannelButtonGroupSelectionChanged, true);
            app.LeftBlueChannelButton = uiradiobutton(app.LeftChannelButtonGroup);
            app.LeftBlueChannelButton.Tag = 'Blue';
            app.LeftBlueChannelButton.Visible = 'off';
            app.LeftBlueChannelButton.Text = 'Blue Channel';
            app.LeftBlueChannelButton.Value = true;
            app.LeftGreenChannelButton = uiradiobutton(app.LeftChannelButtonGroup);
            app.LeftGreenChannelButton.Tag = 'Green';
            app.LeftGreenChannelButton.Visible = 'off';
            app.LeftGreenChannelButton.Text = 'Green Channel';
            app.LeftRedChannelButton = uiradiobutton(app.LeftChannelButtonGroup);
            app.LeftRedChannelButton.Tag = 'Red';
            app.LeftRedChannelButton.Visible = 'off';
            app.LeftRedChannelButton.Text = 'Red Channel';
            app.LeftFarRedChannelButton = uiradiobutton(app.LeftChannelButtonGroup);
            app.LeftFarRedChannelButton.Tag = 'FarRed';
            app.LeftFarRedChannelButton.Visible = 'off';
            app.LeftFarRedChannelButton.Text = 'Far Red Channel';
            app.CenterChannelButtonGroup = uibuttongroup(app.figureWindow);
            app.CenterChannelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CenterChannelButtonGroupSelectionChanged, true);
            app.CenterBlueChannelButton = uiradiobutton(app.CenterChannelButtonGroup);
            app.CenterBlueChannelButton.Tag = 'Blue';
            app.CenterBlueChannelButton.Visible = 'off';
            app.CenterBlueChannelButton.Text = 'Blue Channel';
            app.CenterBlueChannelButton.Value = true;
            app.CenterGreenChannelButton = uiradiobutton(app.CenterChannelButtonGroup);
            app.CenterGreenChannelButton.Tag = 'Green';
            app.CenterGreenChannelButton.Visible = 'off';
            app.CenterGreenChannelButton.Text = 'Green Channel';
            app.CenterRedChannelButton = uiradiobutton(app.CenterChannelButtonGroup);
            app.CenterRedChannelButton.Tag = 'Red';
            app.CenterRedChannelButton.Visible = 'off';
            app.CenterRedChannelButton.Text = 'Red Channel';
            app.CenterFarRedChannelButton = uiradiobutton(app.CenterChannelButtonGroup);
            app.CenterFarRedChannelButton.Tag = 'FarRed';
            app.CenterFarRedChannelButton.Visible = 'off';
            app.CenterFarRedChannelButton.Text = 'Far Red Channel';
            % Show the figure after all components are created
            app.figureWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = colocalization_inspector_GUI_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figureWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)colocalization_inspector_GUI_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figureWindow)
        end
    end
end