classdef trace_inspector_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figureWindow                matlab.ui.Figure
        saveButton                  matlab.ui.control.Button
        radioButtonPanel            matlab.ui.container.ButtonGroup
        acceptedChangepointsButton  matlab.ui.control.RadioButton
        allChangepointsButton       matlab.ui.control.RadioButton
        infoButton                  matlab.ui.control.Button
        extractButton               matlab.ui.control.Button
        instructionText             matlab.ui.control.Label
        stepCountText               matlab.ui.control.Label
        countMenu                   matlab.ui.control.DropDown
        generalUIAxes               matlab.ui.control.UIAxes
    end

    properties (Access = private)
        arrayPos % Where we are in the image sequence
        dumped
        
        % Properties of data
        spotData % Intial copy of data from a selected channel of SpotData found in the gridData struture of selected matfile 
        activeSpotData % The copy of spotData in use depending on the selection from the countMenu
        nTraces % The number of spots
        tmax % Length of a single intensity trace for a given spot 
        
        % Display properties
        screenSize
        showChangepoints % Stores whether all changepoints or only accepted changepoints in fluoresecent intensity to qualify for a step are shown
        
    end
    
    methods (Access = private)
        function updatePlot(app, b)
            if b==0 || b > length(app.activeSpotData)
                b = 1;
            end
            %Generate the Fit for the New Trajectory
            if isempty(app.activeSpotData(b).changepoints)
                nChangepoints = 0;
            else
                [nChangepoints, ~] = size(app.activeSpotData(b).changepoints);
            end
            lastchangepoint = 1;
            traj = app.activeSpotData(b).intensityTrace;
            tmax = length(traj);
            fit = zeros(1,tmax);
            for a = 1:nChangepoints+1
                if a>nChangepoints
                    changepoint = tmax;
                else
                    changepoint = app.activeSpotData(b).changepoints(a,1);
                end
                if changepoint == 0
                    continue
                end
                try
                    fit(lastchangepoint:changepoint) = app.activeSpotData(b).steplevels(a);
                catch
                    fit(lastchangepoint:changepoint) = app.activeSpotData(b).steplevels(end); %Protects against having too few step levels because a changepoint was rejected
                end
                lastchangepoint = changepoint+1; %"+1" just keeps the segments from overlapping
            end
            
            bars = zeros(tmax,1);
            if ~isempty(app.activeSpotData(b).(app.showChangepoints))
                bars(app.activeSpotData(b).(app.showChangepoints)(:,1)) = app.activeSpotData(b).(app.showChangepoints)(:,2);
            end
            
            %Plot the New Trajectory
            cla(app.generalUIAxes);
            yyaxis(app.generalUIAxes,'right')
            xaxis = 1:length(traj);
            if nChangepoints > 0
                bar(app.generalUIAxes,xaxis,bars,'FaceColor',[0 0.5 0],'EdgeColor','none','Barwidth',0.5);
            else
                cla(app.generalUIAxes);
            end 
           app.generalUIAxes.YLabel.String = 'Changepoint Log Odds';
           app.generalUIAxes.YColor = [0 0.5 0]
           app.generalUIAxes.XLim = [0 tmax];
           app.generalUIAxes.Box = 'off'
           yyaxis(app.generalUIAxes,'left')
           app.generalUIAxes.YLabel.String = 'Fluorescence Intensity';
           hold(app.generalUIAxes,'on')
           plot(app.generalUIAxes,xaxis,traj)
           plot(app.generalUIAxes,xaxis,fit,'-r','LineWidth',3);
           hold(app.generalUIAxes,'off')

            if ischar(app.activeSpotData(b).nSteps)
                set(app.stepCountText,'Text','Trace Rejected')
            elseif app.activeSpotData(b).nSteps == 1
                set(app.stepCountText,'Text','1 Step');
            else
                set(app.stepCountText,'Text',[num2str(app.activeSpotData(b).nSteps), ' Steps']);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function trace_inspector_GUI_OpeningFcn(app, varargin)
        
            %Get the data from the spot counter
            [matFile, matPath] = uigetfile('*.mat','Choose a .mat file with data from the step counter');
            load([matPath filesep matFile]);
         
            if nChannels > 1
                channel = questdlg('Multi-Channel Data Found. Select Channel to Display',...
                                    'Select Channel',...
                                     channels{:},channels{1});
            else
                channel = channels{1};
            end
        
            if ~isfield(statsByColor, [channel 'StepHist'])
                msgbox('This program requires photobleaching step data.  Please run the step counter first');
                return
            end
            
            %Set up figure window
            app.screenSize = get(0,'ScreenSize');
            figureWidth = 650;
            figureHeight = 650;
            set(app.figureWindow,'Position',[(app.screenSize(3)-figureWidth)/2 app.screenSize(4)-figureHeight-100 figureWidth figureHeight]);
            set(app.instructionText,'Position',[420 564 154 40]);
            set(app.extractButton,'Position',[420 516 144 38]);
            set(app.infoButton,'Position',[420 477 144 38]);
            set(app.saveButton,'Position',[420 438 144 38]);
            set(app.countMenu,'Position',[84 553 132 44]);
            set(app.radioButtonPanel,'Position',[44 455 212 83]);
            set(app.allChangepointsButton, 'Position', [9 34 176 25]);
            set(app.acceptedChangepointsButton, 'Position', [9 10 175 25]);
            set(app.generalUIAxes,'Position',[55 5 500 400]);
            set(app.stepCountText,'Position',[255 417 113 14]);
            
            %Prepare Data
            for b = 1:length(gridData)
                index2(b) = isfield(gridData(b).([channel 'SpotData']),'nSteps');
            end
            spotData = {gridData(index2).([channel 'SpotData'])};
            app.spotData = vertcat(spotData{ cellfun(@length, spotData) > 1 }); % The argument inside {} tosses images with no spots
            clear gridData spotData
            app.activeSpotData = app.spotData;
            app.nTraces = length(app.spotData);
            app.tmax = length(app.spotData(1).intensityTrace);
            app.arrayPos = 1;
            
            %Test whether step data has been filtered (required for displaying data processed with older versions)
            if ~isfield(app.activeSpotData(1), 'allchangepoints')
                app.showChangepoints = 'changepoints';
                set(app.allChangepointsButton, 'Enable', 'off');
                set(app.acceptedChangepointsButton, 'Enable', 'off');
            else
                app.showChangepoints = 'allchangepoints';
            end
            
            %Plot the First Trajectory
            b = 1;
            updatePlot(app, b);
            
            %Set initial count of trajectories dumped
            app.dumped = 1;
            
        end

        % Value changed function: countMenu
        function countMenu_Callback(app, event)

            selection = event.Value;
            counts = {app.spotData.nSteps};
            %Update active traces to the selection
            if strcmp(selection, 'All Traces')
                index = true(1,app.nTraces);
            elseif strcmp(selection, 'Rejected Traces')
                index = strcmp(counts,'Rejected');
            else %0-10 Steps
                s = regexp(selection,'Traces with (\d+) [*\w]','tokens');
                s = str2double(s{1});
                index = cell2mat(cellfun(@(x) any(x==s),counts,'UniformOutput',false));
            end
            app.activeSpotData = app.spotData(index,:);
            app.nTraces = length(app.activeSpotData);
            app.tmax = length(app.spotData(1).intensityTrace);
            app.arrayPos = 1;
            
            %Update plot
            if app.nTraces > 0
                b = 1;
                updatePlot(app, b);
            else
                cla(app.generalUIAxes);
                set(app.stepCountText,'Text',['No trajectories with ', num2str(selection-3), ' Steps']);
            end
            
        end

        % Button pushed function: extractButton
        function extractButton_Callback(app, event)

            b = app.arrayPos;
            traj = app.activeSpotData(b).intensityTrace;
            varname = ['traj' num2str(app.dumped)];
            assignin('base',varname,traj);
            app.dumped = app.dumped + 1;
            
        end

        % Callback function: figureWindow, figureWindow
        function figureWindow_WindowKeyPressFcn(app, event)

            %Decide what to do
            if isempty(app.activeSpotData)
                return
            end
            
            b = app.arrayPos;
            key = event.Key;
            
            switch key
                case 'n'
                    b=b+1;
                case 'p'
                       b=b-1;
                case 'r'
                    b=round(rand(1)*app.nTraces);
            end
            
            app.arrayPos = b;
            updatePlot(app, b);
            
        end

        % Button pushed function: infoButton
        function infoButton_Callback(app, event)

            b = app.arrayPos;
            app.activeSpotData(b)
        end

        % Selection changed function: radioButtonPanel
        function radioButtonPanel_SelectionChangeFcn(app, event)

            switch app.radioButtonPanel.SelectedObject.Text
                case 'allChangepointsButton'
                    app.showChangepoints = 'allchangepoints';
                case 'acceptedChangepointsButton'
                    app.showChangepoints = 'changepoints';
            end
            b = app.arrayPos;
            updatePlot(app, b);

        end

        % Button pushed function: saveButton
        function saveButton_Callback(app, event)

            figureWidth = 650;
            figureHeight = 500;
            newfig1 = figure('Position',[(app.screenSize(3)-figureWidth)/2 app.screenSize(4)-figureHeight-100 figureWidth figureHeight],'Visible','off');
            
            [outfile,outpath] = uiputfile('plot.pdf','Save File Name');
            if verLessThan('matlab','9.8')
                export_fig([outpath filesep outfile],newfig1,'-pdf','-png','-transparent');
            else
                exportgraphics(app.generalUIAxes,[outpath filesep outfile]);
            end
            close(newfig1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figureWindow = uifigure('Visible', 'off');
            app.figureWindow.Name = 'trace_inspector_GUI';
            app.figureWindow.WindowKeyPressFcn = createCallbackFcn(app, @figureWindow_WindowKeyPressFcn, true);
            app.figureWindow.KeyPressFcn = createCallbackFcn(app, @figureWindow_WindowKeyPressFcn, true);
            app.figureWindow.Tag = 'figureWindow';
            app.generalUIAxes = uiaxes(app.figureWindow);
            ylabel(app.generalUIAxes, 'Fluorescence Intensity')
            app.countMenu = uidropdown(app.figureWindow);
            app.countMenu.Items = {'All Traces', 'Rejected Traces', 'Traces with 0 Steps', 'Traces with 1 Step', 'Traces with 2 Steps', 'Traces with 3 Steps', 'Traces with 4 Steps', 'Traces with 5 Steps', 'Traces with 6 Steps', 'Traces with 7 Steps', 'Traces with 8 Steps', 'Traces with 9 Steps', 'Traces with 10 Steps'};
            app.countMenu.ValueChangedFcn = createCallbackFcn(app, @countMenu_Callback, true);
            app.countMenu.Tag = 'countMenu';
            app.countMenu.Value = 'All Traces';
            app.stepCountText = uilabel(app.figureWindow);
            app.stepCountText.Tag = 'stepCountText';
            app.stepCountText.Text = 'Trace Rejected';
            app.instructionText = uilabel(app.figureWindow);
            app.instructionText.Tag = 'instructionText';
            app.instructionText.Text = {'N to move to next trace'; 'P to move to previous trace'; 'R to move to a random trace'; ''};
            app.extractButton = uibutton(app.figureWindow, 'push');
            app.extractButton.ButtonPushedFcn = createCallbackFcn(app, @extractButton_Callback, true);
            app.extractButton.Tag = 'extractButton';
            app.extractButton.Text = 'Extract Trace to Vector';
            app.infoButton = uibutton(app.figureWindow, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButton_Callback, true);
            app.infoButton.Tag = 'infoButton';
            app.infoButton.Text = 'Display Trace Data';
            app.radioButtonPanel = uibuttongroup(app.figureWindow);
            app.radioButtonPanel.SelectionChangedFcn = createCallbackFcn(app, @radioButtonPanel_SelectionChangeFcn, true);
            app.radioButtonPanel.Title = 'Show:';
            app.radioButtonPanel.Tag = 'radioButtonPanel';
            app.allChangepointsButton = uiradiobutton(app.radioButtonPanel);
            app.allChangepointsButton.Tag = 'allChangepointsButton';
            app.allChangepointsButton.Text = 'All Changepoints';
            app.allChangepointsButton.Value = true;
            app.acceptedChangepointsButton = uiradiobutton(app.radioButtonPanel);
            app.acceptedChangepointsButton.Tag = 'acceptedChangepointsButton';
            app.acceptedChangepointsButton.Text = 'Accepted Changepoints';
            app.saveButton = uibutton(app.figureWindow, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton_Callback, true);
            app.saveButton.Tag = 'saveButton';
            app.saveButton.Text = 'Save Plot to Image';
            % Show the figure after all components are created
            app.figureWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace_inspector_GUI_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figureWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)trace_inspector_GUI_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figureWindow)
        end
    end
end