classdef koffPlotterDlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SampleInformationUIFigure  matlab.ui.Figure
        SelectNewDataButton        matlab.ui.control.Button
        ContinueButton             matlab.ui.control.Button
        Instructions               matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
    end

    
    properties (Access = public, NonCopyable)
        sampleData = {};    % Holds user input that will be returned when the dialog is closed. 
        expDir = {};        % Holds the directory containing experimental data
        fileNames = {};     % Holds the names of experimental datasets
        controlDir = {};    % Holds the directory containing control data
        controlNames = {};  % Holds the names of control datasets
        mode = '';          % Saves information about whether we were given data to start with.
    end
    
    methods (Access = private)
        
        function getFiles(app)
            % Hide table window 
            app.SampleInformationUIFigure.Visible = 'off';
            dummyFig = figure('Visible','off');
            % Ask user to select experimental data
            matFiles = uipickfiles('Prompt','Select data files or folders with your experimental data','Type',{'*.mat'});

            % Parse file names
            for a = 1:length(matFiles)
                % Get file name
                slash = strfind(matFiles{a},filesep);
                app.fileNames{a} = matFiles{a}(slash(end)+1:end); 
            
                % Get Directory
                if isfolder(matFiles{a})
                    app.fileNames{a} = [app.fileNames{a} '.mat'];
                    % Look in the image folder first, where older versions of the code kept .mat files
                    app.expDir{a} = matFiles{a};
                    if ~isfile([app.expDir{a} filesep app.fileNames{a}])
                        % Look in the root directory, where newer versions keep it
                        app.expDir{a} = matFiles{a}(1:slash(end));
                        if ~isfile([app.expDir{a} filesep app.fileNames{a}])
                            warndlg(['No .mat file found for selected folder ' app.expDir{a}]);
                            continue
                        end
                    end
                else
                    app.expDir{a} = matFiles{a}(1:slash(end));
                end
            end

            % Ask user to select control data
            controlFiles = uipickfiles('Prompt','Select data files or folders with your photobleaching control data','Type',{'*.mat'});
            
            % Parse file names
            for a = 1:length(controlFiles)
                % Get file name
                slash = strfind(controlFiles{a},filesep);
                app.controlNames{a} = controlFiles{a}(slash(end)+1:end); 
            
                % Get Directory
                if isfolder(controlFiles{a})
                    app.controlNames{a} = [app.controlNames{a} '.mat'];
                    app.controlDir{a} = controlFiles{a};
                    if ~isfile([app.controlDir{a} filesep app.controlNames{a}])
                        warndlg(['No .mat file found for selected folder ' app.expDir{a}]);
                        continue
                    end
                else
                    app.controlDir{a} = controlFiles{a}(1:slash(end));
                end
            end

            % Fill in table
            tableData = cell(length(app.fileNames),3);
            tableData(:,1) = app.fileNames';
            [tableData{:,3}] = deal('Choose');
            app.UITable.Data = tableData;
            app.UITable.ColumnFormat = {[] [] app.controlNames};

            % Return focus to table window
            delete(dummyFig);
            app.SampleInformationUIFigure.Visible = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tableData, controlNames)
            if isempty(tableData)
                app.mode = 'new';
            else
                app.mode = 'old';
                app.controlNames = controlNames; clear controlNames
            end
            
            if strcmp(app.mode, 'new')
                getFiles(app);
            else
               app.UITable.Data = tableData;
               app.UITable.ColumnFormat = {[] [] app.controlNames};
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            % Save the relevant data to workspace variable
            assignin('base','infoTable', app.UITable.Data);
            if strcmp(app.mode, 'new')
                assignin('base','fileNames', app.fileNames);
                assignin('base','expDir', app.expDir);
                assignin('base','controlNames',app.controlNames);
                assignin('base','controlDir',app.controlDir);    
            end

            % Close the dialog box
            close(app.SampleInformationUIFigure);
            

        end

        % Callback function
        function SampleInformationUIFigureCloseRequest(app, event)
            
            
        end

        % Button pushed function: SelectNewDataButton
        function SelectNewDataButtonPushed(app, event)
            app.mode = 'new';
            getFiles(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SampleInformationUIFigure = uifigure('Visible', 'off');
            app.SampleInformationUIFigure.Name = 'Sample Information';
            app.UITable = uitable(app.SampleInformationUIFigure);
            app.UITable.ColumnName = {'Sample'; 'Experimental Condition'; 'Bleaching Control'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            app.Instructions = uilabel(app.SampleInformationUIFigure);
            app.Instructions.Text = {'Complete the table below to provide information about your data.'; ''; 'Experimental conditions are used to group your data - for example, they can be things like "Wild-type", "Mutant", "Early", "Late", etc.'; ''; 'Select the matching control data (used to calculate k_bleach) from the dropdown menu'};
            app.ContinueButton = uibutton(app.SampleInformationUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.SelectNewDataButton = uibutton(app.SampleInformationUIFigure, 'push');
            app.SelectNewDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNewDataButtonPushed, true);
            app.SelectNewDataButton.Text = 'Select New Data';
            % Show the figure after all components are created
            app.SampleInformationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = koffPlotterDlg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SampleInformationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SampleInformationUIFigure)
        end
    end
end